classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        ThresholdSelectionMenu          matlab.ui.container.Menu
        DynamicThresholdMenu            matlab.ui.container.Menu
        OptimizationMenu                matlab.ui.container.Menu
        MainPanel                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        DataPanel                       matlab.ui.container.Panel
        DatasetSelectionPanel           matlab.ui.container.Panel
        D_DatasetAttributesLabel        matlab.ui.control.Label
        D_StandardDeviationLabel        matlab.ui.control.Label
        D_MeanLabel                     matlab.ui.control.Label
        D_MinimumLabel                  matlab.ui.control.Label
        D_MaximumLabel                  matlab.ui.control.Label
        D_ImportCustomDatasetEditField  matlab.ui.control.EditField
        D_ImportCustomDatasetLabel      matlab.ui.control.Label
        D_BrowseCustomDatasetButton     matlab.ui.control.Button
        D_LoadDataButton                matlab.ui.control.Button
        D_SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_2             matlab.ui.control.Label
        D_SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_2          matlab.ui.control.Label
        D_SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel               matlab.ui.control.Label
        D_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel            matlab.ui.control.Label
        D_NextButton                    matlab.ui.control.Button
        D_RawTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        D_RawTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        PreprocessingTab                matlab.ui.container.Tab
        PreprocessingPanel              matlab.ui.container.Panel
        P_AugmentedTrainingCheckBock    matlab.ui.control.CheckBox
        P_intensitySlider               matlab.ui.control.Slider
        intensitySliderLabel            matlab.ui.control.Label
        P_Choice0fAugmentationListBox   matlab.ui.control.ListBox
        ChoiceofaugmentationListBoxLabel  matlab.ui.control.Label
        P_RatioTestSwitchLabel          matlab.ui.control.Label
        P_RatioTestSwitchEditField      matlab.ui.control.NumericEditField
        P_SplitTestSetForDynamicSwitchCheckBox  matlab.ui.control.CheckBox
        P_RatioTestValLabel             matlab.ui.control.Label
        P_SelectedFileTestValDropDown   matlab.ui.control.DropDown
        SelectedFileLabel_5             matlab.ui.control.Label
        P_SelectedChannelTestValDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_5          matlab.ui.control.Label
        P_SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_4             matlab.ui.control.Label
        P_SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_4          matlab.ui.control.Label
        P_SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel_3             matlab.ui.control.Label
        P_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_3          matlab.ui.control.Label
        P_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        P_RatioTestValEditField         matlab.ui.control.NumericEditField
        P_MethodButtonGroup             matlab.ui.container.ButtonGroup
        P_RescaleButton                 matlab.ui.control.RadioButton
        P_StandardizeButton             matlab.ui.control.RadioButton
        P_RawDataButton                 matlab.ui.control.RadioButton
        P_PreviousButton                matlab.ui.control.Button
        P_NextButton                    matlab.ui.control.Button
        P_PreprocessedTestValDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        P_PreprocessedTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        P_PreprocessedTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        ModelTrainingTab                matlab.ui.container.Tab
        ModelTrainingPanel              matlab.ui.container.Panel
        MT_ExportConfigForSelectionButton  matlab.ui.control.Button
        MT_LoadConfigFromFileButton     matlab.ui.control.Button
        MT_OptimizeAllButton            matlab.ui.control.Button
        MT_TrainAllButton               matlab.ui.control.Button
        MT_ModelPreviewLabel            matlab.ui.control.Label
        MT_PreviewPanel                 matlab.ui.container.Panel
        MT_PreviewUITable               matlab.ui.control.Table
        NoModelLoadedforPreviewLabel    matlab.ui.control.Label
        MT_PreviewUIAxes                matlab.ui.control.UIAxes
        MT_StatisticalModelsPanel       matlab.ui.container.Panel
        MT_S_OptimizeAllButton          matlab.ui.control.Button
        MT_S_AddAllModelsButton         matlab.ui.control.Button
        MT_S_OptimizeSelectionButton    matlab.ui.control.Button
        MT_S_TrainAllModelsButton       matlab.ui.control.Button
        MT_S_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_S_ClearModelsButton          matlab.ui.control.Button
        MT_S_AddModelButton             matlab.ui.control.Button
        MT_S_ModelsListBox              matlab.ui.control.ListBox
        MT_ClassicMachineLearningPanel  matlab.ui.container.Panel
        MT_CML_OptimizeAllButton        matlab.ui.control.Button
        MT_CML_AddAllModelsButton       matlab.ui.control.Button
        MT_CML_OptimizeSelectionButton  matlab.ui.control.Button
        MT_CML_TrainAllModelsButton     matlab.ui.control.Button
        MT_CML_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_CML_ClearModelsButton        matlab.ui.control.Button
        MT_CML_AddModelButton           matlab.ui.control.Button
        MT_CML_ModelsListBox            matlab.ui.control.ListBox
        MT_DeepNeuralNetworksPanel      matlab.ui.container.Panel
        MT_DNN_OptimizeAllButton        matlab.ui.control.Button
        MT_DNN_TrainingPlotsCheckBox    matlab.ui.control.CheckBox
        MT_DNN_ParallelCheckBox         matlab.ui.control.CheckBox
        MT_DNN_AddAllModelsButton       matlab.ui.control.Button
        MT_DNN_OptimizeSelectionButton  matlab.ui.control.Button
        MT_DNN_TrainAllModelsButton     matlab.ui.control.Button
        MT_DNN_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_DNN_ClearModelsButton        matlab.ui.control.Button
        MT_DNN_AddModelsButton          matlab.ui.control.Button
        MT_DNN_ModelsListBox            matlab.ui.control.ListBox
        MT_PreviousButton               matlab.ui.control.Button
        MT_NextButton                   matlab.ui.control.Button
        DetectionEvaluationTab          matlab.ui.container.Tab
        DetectionEvaluationPanel        matlab.ui.container.Panel
        DE_DetectionPanel               matlab.ui.container.Panel
        DE_ZRangeLabel                  matlab.ui.control.Label
        DE_WindowSizeLabel              matlab.ui.control.Label
        DE_AnomalyPaddingLabel          matlab.ui.control.Label
        DE_ZRangeSlider                 matlab.ui.control.Slider
        DE_WindowSizeSlider             matlab.ui.control.Slider
        DE_AnomalyPaddingSlider         matlab.ui.control.Slider
        DE_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        DE_DetectionTabGroup            matlab.ui.container.TabGroup
        DE_PlotsTab                     matlab.ui.container.Tab
        DE_DetectionShownForLabel_2     matlab.ui.control.Label
        DE_DetectionShownForLabel       matlab.ui.control.Label
        DE_PlotsScoresUITable           matlab.ui.control.Table
        DE_PlotsDataUIAxes              matlab.ui.control.UIAxes
        DE_PlotsAnomalyScoresUIAxes     matlab.ui.control.UIAxes
        DE_TableTab                     matlab.ui.container.Tab
        LegendLabel                     matlab.ui.control.Label
        ColumnwisemaximaLabel           matlab.ui.control.Label
        RowwisemaximaLabel              matlab.ui.control.Label
        DE_ExportTableButton            matlab.ui.control.Button
        DE_FullDetectionUITable         matlab.ui.control.Table
        DE_ComputationTimeTab           matlab.ui.container.Tab
        DE_CompTimeMetricSelectionButton  matlab.ui.control.Button
        DE_CompTimeUIAxes               matlab.ui.control.UIAxes
        DE_RunDetectionForAllButton     matlab.ui.control.Button
        DE_RunDetectionForLabel         matlab.ui.control.Label
        DE_RunDetectionForSelectedButton  matlab.ui.control.Button
        DE_FaultyDataSelectionDropDown  matlab.ui.control.DropDown
        SelectfaultyDataLabel           matlab.ui.control.Label
        DE_TrainedModelsPanel           matlab.ui.container.Panel
        DE_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        DE_ImportTrainedModelsButton    matlab.ui.control.Button
        DE_SaveSelectedModelsButton     matlab.ui.control.Button
        DE_TrainedModelsListBox         matlab.ui.control.ListBox
        SimulinkDetectionTab            matlab.ui.container.Tab
        SimulinkDetectionPanel          matlab.ui.container.Panel
        SD_DetectionPanel               matlab.ui.container.Panel
        SD_FaultyDataSelectionDropDown  matlab.ui.control.DropDown
        SelectfaultyDataLabel_2         matlab.ui.control.Label
        SD_DetectionShownForLabel       matlab.ui.control.Label
        SD_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel_4        matlab.ui.control.Label
        SD_OpenmodelCheckBox            matlab.ui.control.CheckBox
        SD_RunDetectionForSelectedButton  matlab.ui.control.Button
        SD_TrueAnomaliesUIAxes          matlab.ui.control.UIAxes
        SD_FoundAnomaliesUIAxes         matlab.ui.control.UIAxes
        SD_TrainedModelsPanel           matlab.ui.container.Panel
        SD_ImportTrainedModelsButton    matlab.ui.control.Button
        SD_SaveSelectedModelsButton     matlab.ui.control.Button
        SD_TrainedModelsListBox         matlab.ui.control.ListBox
        DynamicSwitchTab                matlab.ui.container.Tab
        DynamicSwitchPanel              matlab.ui.container.Panel
        DS_SelectedFileTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_6             matlab.ui.control.Label
        DS_SelectedChannelTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_6          matlab.ui.control.Label
        DS_ExportButton                 matlab.ui.control.Button
        DS_TrainingAndEvaluationPanel   matlab.ui.container.Panel
        DS_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel_2        matlab.ui.control.Label
        DS_ScoreDropDown                matlab.ui.control.DropDown
        ScoreforAutolabelingLabel       matlab.ui.control.Label
        DS_EvaluationLamp               matlab.ui.control.Lamp
        DS_TrainLamp                    matlab.ui.control.Lamp
        DS_AutoLabelLamp                matlab.ui.control.Lamp
        DS_TrainClassifierButton        matlab.ui.control.Button
        DS_RunEvaluationsButton         matlab.ui.control.Button
        DS_AutoLabelDatasetButton       matlab.ui.control.Button
        DS_ResultsLabel                 matlab.ui.control.Label
        DS_ResultsUITable               matlab.ui.control.Table
        DE_PreprocessedTestSwitchDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        AutoRunTab                      matlab.ui.container.Tab
        AutoRunPanel                    matlab.ui.container.Panel
        AR_ResultsLabel                 matlab.ui.control.Label
        AR_ResultsUITable               matlab.ui.control.Table
        AR_AutoTrainingAndEvaluationPanel  matlab.ui.container.Panel
        AR_intensitySliderLabel         matlab.ui.control.Label
        AR_ChoiceofaugmentationListBoxLabel  matlab.ui.control.Label
        AR_intensitySlider              matlab.ui.control.Slider
        AR_Choice0fAugmentationListBox  matlab.ui.control.ListBox
        AR_VisualizeDataButton          matlab.ui.control.Button
        AR_AugmentedDataCheckBock       matlab.ui.control.CheckBox
        AR_AugmentedTrainingCheckBock   matlab.ui.control.CheckBox
        AR_TrainingPlotsCheckBox        matlab.ui.control.CheckBox
        AR_ParallelCheckBox             matlab.ui.control.CheckBox
        AR_RatioTestValLabel            matlab.ui.control.Label
        AR_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        AR_RatioTestValEditField        matlab.ui.control.NumericEditField
        AR_DatasetEditField             matlab.ui.control.EditField
        AR_DatasetLabel                 matlab.ui.control.Label
        AR_BrowseButton                 matlab.ui.control.Button
        AR_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        BestFscoreThresholdLabel        matlab.ui.control.Label
        AR_UseFractionEditField         matlab.ui.control.NumericEditField
        FractionofDatasettoUseLabel     matlab.ui.control.Label
        AR_RunEvaluationButton          matlab.ui.control.Button
        MT_DNN_ContextMenu              matlab.ui.container.ContextMenu
        MT_DNN_DeleteMenu               matlab.ui.container.Menu
        MT_DNN_ShowModelParametersMenu  matlab.ui.container.Menu
        MT_CML_ContextMenu              matlab.ui.container.ContextMenu
        MT_CML_DeleteMenu               matlab.ui.container.Menu
        MT_CML_ShowModelParametersMenu  matlab.ui.container.Menu
        MT_S_ContextMenu                matlab.ui.container.ContextMenu
        MT_S_DeleteMenu                 matlab.ui.container.Menu
        MT_S_ShowModelParametersMenu    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Status Information        
        info_dataLoaded = false;            % True if data was loaded
        info_numTrainFiles = 0;             % Number of training files
        info_numTestFiles = 0;              % Number of all testing files
        info_numChannels = false;           % Number of channels in dataset
        info_detectionRun = false;          % True if detection has been done
        info_optimizationRunning = false;   % True if an optimization process is running
        %%

        %% Data
        dataTrainRaw                    % Initial fault free raw data
        dataTestRaw                     % Initial faulty raw data
        

        filesTrain                      % Array of filenames of the training data
        filesTestAll                    % Array of filenames of the entire testing dataset
        filesTest                       % Array of filenames of the final testing data (after splitting the dataset) 
        filesTestVal                    % Array of filenames of the anomalous validation data
        filesTestSwitch                 % Array of filenames of the dynamic switch data
        allTestFiles                    % Array of filenames of all test files without the dynamic switch files

        labelsTrain                     % Training labels   
        labelsTestAll                   % Labels for all test files
        labelsTest                      % Labels for final testing data (after splitting the dataset) 
        labelsTestVal                   % Labels for anomalous validation data
        labelsTestSwitch                % Labels for dynamic switch data
        allTestLabels                   % Labels for all test files without the dynamic switch labels

        dataTrain                       % Preprocessed fault free data
        dataTestAll                     % All testing data
        dataTest                        % Final testing data (after splitting the dataset) 
        dataTestVal                     % Anomalous validation data
        dataTestSwitch                  % Dynamic switch data
        allTestData                     % All test data without the dynamic switch data
        dataAugmented                   % data with global transformation
        
        % These timestamps aren't used in the platform, but could be of interest for
        % future upgrades
        timestampsTrain                 % Timestamps of training data
        timestampsTestAll               % Timestamps of all test data
        timestampsTest                  % Timestamps of the final testing data (after splitting the dataset) 
        timestampsTestSwitch            % Timestamps of the dynamic switch data
        timestampsTestVal               % Timestamps of the anoamlous validation data
        allTestTimestamps               % Timestamps of all test files without the dynmaic switch data

        %% Preprocessing
        maximum                         % Maximum value of training data
        minimum                         % Minimum value of training data

        mu                              % Mean of training data
        sigma                           % Standard deviation of training data
        %%

        %% Models
        trainedModels                   % Trained models struct

        %% Settings
        settings_exportLogdata = false; % Whether to export logdata during optimization
        settings_thresholds = ["bestFscorePointwise", "dynamic"]; % Selected thresholds
        settings_dynamicThreshold       % Dynamic threshold settings

        %% Detection
        currentDetection_threshold = "bestFscorePointwise"; % Currently selected threshold
        currentDetection_anomalyScores            % Currently shown anomaly scores in detection tab
        currentDetection_YTest                    % YTest for currently shown prediction
        currentDetection_labels                   % Labels of currently shown prediction
        currentDetection_id                % Name of Model used for current prediction
        currentDetection_type                % Type of Model used for current prediction (DNN, CML, ...)
        currentDetection_options                  % Options of the Model currently selected for detection on the detection panel


        fullScores = table(["Computation Time (seconds)"; ...
                            "F1 Score (point-wise)"; ...
                            "F1 Score (event-wise)"; ...
                            "F1 Score (point-adjusted)"; ...
                            "F1 Score (composite)"; ...
                            "F0.5 Score (point-wise)"; ...
                            "F0.5 Score (event-wise)"; ...
                            "F0.5 Score (point-adjusted)"; ...
                            "F0.5 Score (composite)"; ...
                            "Precision (point-wise)"; ...
                            "Precision (event-wise)"; ...
                            "Precision (point-adjusted)"; ...
                            "Recall (point-wise)"; ...
                            "Recall (event-wise)"; ...
                            "Recall (point-adjusted)"]); % All scores for all models for the current threshold

        compTimeMetrics = ["F1 Score (point-wise)"]; % Selected metrics shown in the computation time plot
        %%
        
        %% Dynamic Switch
        ds_classifier                   % Classification network for the dynamic switch
        ds_bestModels                   % Labels for training the dynamic switch

        %% Popup Apps
        popup_ModelSelection_DNN           % Popup for selecting the Deep Neural Network models
        popup_ModelSelection_CML           % Popup for selecting the Classic Machine Learning models
        popup_ModelSelection_S             % Popup for selecting the Statistical models
        popup_OptimizeParameters           % Popup for optimizing hyperparameters
        popup_OptimizeAll                  % Popup for optimizing all selected models
        popup_ThresholdSelection           % Popup for selecting thresholds
        popup_OptimizationSettings         % Popup for the optimization settings
        popup_CompTimeMetricSelection      % Popup for selecting the computation time metrics
        popup_DynamicThresholdSettings     % Popup for configuring the dynmaic threshold
        popup_VisualizationData            % Popup for visualizing the global transformation
        %%
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% App interaction and helper functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Disable/Enable GUI functions

        function oldPointer = beginTask(app)
            oldPointer = get(app.UIFigure, 'pointer');

            app.DataPanel.Enable = 'off';
            app.PreprocessingPanel.Enable = 'off';
            app.ModelTrainingPanel.Enable = 'off';
            app.DetectionEvaluationPanel.Enable = 'off';
            app.AutoRunPanel.Enable = 'off';
            app.DynamicSwitchPanel.Enable = 'off';
            app.SimulinkDetectionPanel.Enable = 'off';

            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function endTask(app, oldPointer)
            app.DataPanel.Enable = 'on';
            app.PreprocessingPanel.Enable = 'on';
            app.ModelTrainingPanel.Enable = 'on';
            app.DetectionEvaluationPanel.Enable = 'on';
            app.AutoRunPanel.Enable = 'on';
            app.DynamicSwitchPanel.Enable = 'on';
            app.SimulinkDetectionPanel.Enable = 'on';

            set(app.UIFigure, 'pointer', oldPointer);
        end

%% Data
        function resetAllData(app)
            app.dataTrainRaw = [];
            app.dataTestRaw = [];
            app.timestampsTrain = [];
            app.timestampsTestAll = [];
            app.timestampsTest = [];
            app.timestampsTestVal = [];
            app.timestampsTestSwitch = [];
            app.allTestTimestamps = [];
            app.labelsTrain = [];
            app.labelsTestAll = [];
            app.labelsTest = [];
            app.labelsTestVal = [];
            app.labelsTestSwitch = [];
            app.allTestLabels = [];
            app.filesTrain = [];
            app.filesTestAll = [];
            app.filesTest = [];
            app.filesTestVal = [];
            app.filesTestSwitch = [];
            app.allTestFiles = [];
        end

        function createPreviewLegend(app, dataset, fileIdx)
            switch dataset
                case 'train-raw'
                    axes = app.D_RawTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{fileIdx, 1});
                case 'test-raw'
                    axes = app.D_RawTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.allTestLabels{fileIdx, 1});
                case 'train'
                    axes = app.P_PreprocessedTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{fileIdx, 1});
                case 'test'
                    axes = app.P_PreprocessedTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTest{fileIdx, 1});
                case 'test-val'
                    axes = app.P_PreprocessedTestValDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTestVal{fileIdx, 1});
                case 'test-switch'
                    axes = app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTestSwitch{fileIdx, 1});
            end
            
            YLims = get(axes, 'YLim');
            limits = [];
            for i = 1:size(indices, 2)
                limits(1,i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limits)
                patch(axes, indices, limits, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end   

        function highlightTableMaxima(~, UITable, type, rowIncrement)
            if ~exist('rowIncrement', 'var')
                rowIncrement = 0;
            end

            scoreMatrix = UITable.Data{(1 + rowIncrement):end, 2:end};

            if strcmp(type, 'row-wise') || strcmp(type, 'all')
                % Add bold highlight for rowise maxima
                styleBold = uistyle;
                styleBold.FontWeight = 'bold';
                
                rows_max_rowwise = [];
                columns_max_rowwise = [];
    
                for i = 1:size(scoreMatrix, 1)
                    [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                    
                    for j = 1:numel(col)
                        rows_max_rowwise(end + 1, 1) = i;
                        columns_max_rowwise(end + 1, 1) = col(j) + 1;
                    end
                end
                
                if ~isempty(rows_max_rowwise) && ~isempty(columns_max_rowwise)
                    rows_max_rowwise = rows_max_rowwise + rowIncrement;
                    addStyle(UITable, styleBold, 'cell', [rows_max_rowwise, columns_max_rowwise]);
                end
            end

            if strcmp(type, 'column-wise') || strcmp(type, 'all')
                % Add green background highlight for column-wise maxima
                styleGreen = uistyle;
                styleGreen.BackgroundColor = '#86F2BC';
    
                rows_max_columnwise = [];
                columns_max_columnwise = [];
    
                for i = 1:size(scoreMatrix, 2)
                    [row, ~] = find(scoreMatrix(:, i) == max(scoreMatrix(:, i), [], 1));
                    
                    for j = 1:numel(row)
                        rows_max_columnwise(end + 1, 1) = row(j);
                        columns_max_columnwise(end + 1, 1) = i + 1;
                    end
                end
                
                if ~isempty(rows_max_columnwise) && ~isempty(columns_max_columnwise)
                    rows_max_columnwise = rows_max_columnwise + rowIncrement;
                    addStyle(UITable, styleGreen, 'cell', [rows_max_columnwise, columns_max_columnwise]);
                end
            end
        end

        function showModelPreview(app, options)
            app.NoModelLoadedforPreviewLabel.Visible = 'off';
            app.MT_PreviewUITable.Visible = 'off';
            cla(app.MT_PreviewUIAxes);

            app.MT_PreviewUITable.Data = [];

            newData = table("Parameter", "Value");

            newData = [newData; {"Model:", options.model}];

            switch options.type
                case 'DNN'            
                    newData = [newData; {"Type:", "Deep Learning"}];
                case 'CML'            
                    newData = [newData; {"Type:", "Classic Machine Learning"}];
                case 'S'            
                    newData = [newData; {"Type:", "Statistical"}];
            end

            if isfield(options, 'modelType')
                newData = [newData; {"Model Type:", options.modelType}];
            end

            if isfield(options, 'scoringFunction')
                newData = [newData; {"Scoring Function:", options.scoringFunction}];
            end

            if isfield(options, 'isMultivariate')
                if options.isMultivariate
                    newData = [newData; {"Is Multivariate:", "yes"}];
                else
                    newData = [newData; {"Is Multivariate:", "no"}];
                end
            end

            if isfield(options, 'useSubsequences')
                if options.useSubsequences
                    newData = [newData; {"Use Subsequences:", "yes"}];
                else
                    newData = [newData; {"Use Subsequences:", "no"}];
                end
            end

            if isfield(options, 'hyperparameters')
                if isfield(options.hyperparameters, 'model')
                    fields = fieldnames(options.hyperparameters.model);
                    for i = 1:numel(fields)
                        newData = [newData; {fields{i}, string(options.hyperparameters.model.(fields{i}).value)}];
                    end
                end
                if isfield(options.hyperparameters, 'data')
                    fields = fieldnames(options.hyperparameters.data);
                    for i = 1:numel(fields)
                        newData = [newData; {fields{i}, string(options.hyperparameters.data.(fields{i}).value)}];
                    end
                end
                if isfield(options.hyperparameters, 'training')
                    fields = fieldnames(options.hyperparameters.training);
                    for i = 1:numel(fields)
                        newData = [newData; {fields{i}, string(options.hyperparameters.training.(fields{i}).value)}];
                    end
                end    
            end

            app.MT_PreviewUITable.Data = newData;
            
            if strcmp(options.type, 'DNN')
                graph = app.createModelGraphForPreview(options);
                plot(app.MT_PreviewUIAxes, graph);
                app.MT_PreviewUIAxes.Title.String = 'Network Architecture';
            else
                app.MT_PreviewUIAxes.Title.String = '';
            end

            app.MT_PreviewUITable.Visible = 'on';
        end

%% Toggle functions for disabling/enabling elements
        
        function toggleLamp(~, lamp, state)
            if state
                lamp.Color = 'green';
            else
                lamp.Color = '#cccccc';
            end
        end
        
        function toggleTrainButtons(app, modelType, state)
            switch modelType
                case 'DNN'
                    if state                
                        app.MT_DNN_TrainAllModelsButton.Enable = 'on';
                        app.MT_DNN_TrainSelectedModelsButton.Enable = 'on';
                        app.MT_DNN_ParallelCheckBox.Enable = 'on';
                        app.MT_DNN_TrainingPlotsCheckBox.Enable = 'on';
                        app.MT_DNN_OptimizeSelectionButton.Enable = 'on';
                        app.MT_DNN_OptimizeAllButton.Enable = 'on';
                    else
                        app.MT_DNN_TrainAllModelsButton.Enable = 'off';
                        app.MT_DNN_TrainSelectedModelsButton.Enable = 'off';
                        app.MT_DNN_ParallelCheckBox.Enable = 'off';
                        app.MT_DNN_TrainingPlotsCheckBox.Enable = 'off';
                        app.MT_DNN_OptimizeSelectionButton.Enable = 'off';
                        app.MT_DNN_OptimizeAllButton.Enable = 'off';
                    end
                case 'CML'
                    if state
                        app.MT_CML_TrainAllModelsButton.Enable = 'on';
                        app.MT_CML_TrainSelectedModelsButton.Enable = 'on';
                        app.MT_CML_OptimizeSelectionButton.Enable = 'on';
                        app.MT_CML_OptimizeAllButton.Enable = 'on';
                    else
                        app.MT_CML_TrainAllModelsButton.Enable = 'off';
                        app.MT_CML_TrainSelectedModelsButton.Enable = 'off';
                        app.MT_CML_OptimizeSelectionButton.Enable = 'off';
                        app.MT_CML_OptimizeAllButton.Enable = 'off';
                    end
                case 'S'
                    if state
                        app.MT_S_TrainAllModelsButton.Enable = 'on';
                        app.MT_S_TrainSelectedModelsButton.Enable = 'on';
                        app.MT_S_OptimizeSelectionButton.Enable = 'on';
                        app.MT_S_OptimizeAllButton.Enable = 'on';
                    else
                        app.MT_S_TrainAllModelsButton.Enable = 'off';
                        app.MT_S_TrainSelectedModelsButton.Enable = 'off';
                        app.MT_S_OptimizeSelectionButton.Enable = 'off';
                        app.MT_S_OptimizeAllButton.Enable = 'off';
                    end
            end
        end

        function toggleDynamicSwitchPanel(app, state)
            if state
                app.DS_AutoLabelDatasetButton.Enable = 'on';
                app.DS_TrainClassifierButton.Enable = 'on';
                app.DS_RunEvaluationsButton.Enable = 'on';
            else
                app.DS_AutoLabelDatasetButton.Enable = 'off';
                app.DS_TrainClassifierButton.Enable = 'off';
                app.DS_RunEvaluationsButton.Enable = 'off';
            end
        end

        function togglePlotsForThresholding(app, threshold)
            if strcmp(threshold, "dynamic")
                app.DE_AnomalyPaddingSlider.Enable = 'on';
                app.DE_AnomalyPaddingLabel.Enable = 'on';
                app.DE_WindowSizeSlider.Enable = 'on';
                app.DE_WindowSizeLabel.Enable = 'on';
                app.DE_ZRangeSlider.Enable = 'on';
                app.DE_ZRangeLabel.Enable = 'on';
            else
                app.DE_AnomalyPaddingSlider.Enable = 'off';
                app.DE_AnomalyPaddingLabel.Enable = 'off';
                app.DE_WindowSizeSlider.Enable = 'off';
                app.DE_WindowSizeLabel.Enable = 'off';
                app.DE_ZRangeSlider.Enable = 'off';
                app.DE_ZRangeLabel.Enable = 'off';
            end
        end

%% Model preview

        function graph = createModelGraphForPreview(~, options)
            layers = getLayers(options, 1, 1);
            a = layers.Connections;
            b = extractBefore(a.Source, '/');
            c = extractBefore(a.Destination, '/');
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end

%% Model listboxes

        function [label, id, intanceNumber] = getLabelForModel(app, model, type)
            switch type
                case 'DNN'
                    listBox = app.MT_DNN_ModelsListBox;
                case 'CML'
                    listBox = app.MT_CML_ModelsListBox;
                case 'S'
                    listBox = app.MT_S_ModelsListBox;
            end

            usedInstanceNumbers = [];
            for i = 1:length(listBox.ItemsData)
                if strcmp(model, listBox.ItemsData(i).options.model)
                    usedInstanceNumbers = [usedInstanceNumbers, listBox.ItemsData(i).options.instanceNumber];
                end
            end

            intanceNumber = 1;
            while true
                if ismember(intanceNumber, usedInstanceNumbers)
                    intanceNumber = intanceNumber + 1;
                    continue;
                else
                    break;
                end
            end

            label = sprintf('%s  (%d)', model, intanceNumber);
            id = sprintf('%s_%d', model, intanceNumber);
            id = replace(id, ' ', '_');
            id = replace(id, '-', '_');
            id = replace(id, '(', '');
            id = replace(id, ')', '');
        end
     
%% Detection

        function removeDetection(app, items)
            for i = 1:length(items)
                id = items(i).options.id;
                if isfield(app.trainedModels, id)
                    app.trainedModels = rmfield(app.trainedModels, id);
                end
                if ismember(id, app.fullScores.Properties.VariableNames)
                    app.fullScores = removevars(app.fullScores, id);
                end
                if strcmp(app.currentDetection_id, id)
                    cla(app.DE_PlotsDataUIAxes);
                    cla(app.DE_PlotsAnomalyScoresUIAxes);
                    app.currentDetection_anomalyScores = [];
                    app.currentDetection_labels = [];
                    app.currentDetection_id = "";
                    app.currentDetection_type = "";
                    app.currentDetection_YTest = [];
                    app.currentDetection_options = [];
                    app.info_detectionRun = false;
                end
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Data preparation functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Data preprocessing

        function showDatasetParameters(app)
            % Initialize channel selection dropdown menus for
            % preview
            items = arrayfun(@(a)num2str(a), 1:app.info_numChannels, 'uni', 0);
            app.D_SelectedChannelTrainDropDown.Items = items;
            app.D_SelectedChannelTestDropDown.Items = items;
            app.P_SelectedChannelTrainDropDown.Items = items;
            app.P_SelectedChannelTestDropDown.Items = items;
            app.P_SelectedChannelTestValDropDown.Items = items;
            app.DS_SelectedChannelTestSwitchDropDown.Items = items;
            
            % Show parameters of data on preprocessing panel
            if ~isempty(app.dataTrainRaw)
                numFiles = size(app.dataTrainRaw, 1);
    
                maxima = zeros(numFiles, app.info_numChannels);
                minima = zeros(numFiles, app.info_numChannels);
                for i = 1:numFiles
                    maxima(i, :) =  max(app.dataTrainRaw{i, 1}, [], 1);
                    minima(i, :) =  min(app.dataTrainRaw{i, 1}, [], 1);
                end
                app.maximum = max(maxima, [], 1);
                app.minimum = min(minima, [], 1);


                fullData = [];
                for i = 1:size(app.dataTrainRaw, 1)
                    fullData = [fullData; app.dataTrainRaw{i, 1}];
                end
                [~, app.mu, app.sigma] = zscore(fullData, 0, 1);
            elseif ~isempty(app.dataTestRaw)
                numFiles = size(app.dataTestRaw, 1);
    
                maxima = zeros(numFiles, app.info_numChannels);
                minima = zeros(numFiles, app.info_numChannels);
                for i = 1:numFiles
                    maxima(i, :) =  max(app.dataTestRaw{i, 1}, [], 1);
                    minima(i, :) =  min(app.dataTestRaw{i, 1}, [], 1);
                end
                app.maximum = max(maxima, [], 1);
                app.minimum = min(minima, [], 1);

                
                fullData = [];
                for i = 1:size(app.dataTestRaw, 1)
                    fullData = [fullData; app.dataTestRaw{i, 1}];
                end
                [~, app.mu, app.sigma] = zscore(fullData, 0, 1);
            end

            if ~isempty(app.maximum)
                app.D_MaximumLabel.Text = sprintf('Maximum: %f', max(app.maximum));
            else
                app.D_MaximumLabel.Text = 'Maximum: -';
            end
            if ~isempty(app.minimum)
                app.D_MinimumLabel.Text = sprintf('Minimum: %f', min(app.minimum));
            else
                app.D_MinimumLabel.Text = 'Minimum: -';
            end
            if ~isempty(app.mu)
                app.D_MeanLabel.Text = sprintf('Mean: %f', mean(app.mu));
            else
                app.D_MeanLabel.Text = 'Mean: -';
            end
            if ~isempty(app.sigma)
                app.D_StandardDeviationLabel.Text = sprintf('Standard Deviation: %f', mean(app.sigma));
            else
                app.D_StandardDeviationLabel.Text = 'Standard Deviation: -';
            end
        end

        function plotLoadedData(app, dataset, fileIdx, chIdx)
            switch dataset
                case 'train-raw'
                    axes = app.D_RawTrainingDatasetPreviewUIAxes;
                    data = app.dataTrainRaw{fileIdx, 1}(:, chIdx);
                case 'test-raw'
                    axes = app.D_RawTestingDatasetPreviewUIAxes;
                    data = app.dataTestRaw{fileIdx, 1}(:, chIdx);
                case 'train'
                    axes = app.P_PreprocessedTrainingDatasetPreviewUIAxes;
                    data = app.dataTrain{fileIdx, 1}(:, chIdx);
                case 'test'
                    axes = app.P_PreprocessedTestingDatasetPreviewUIAxes;
                    data = app.dataTest{fileIdx, 1}(:, chIdx);
                case 'test-val'
                    axes = app.P_PreprocessedTestValDatasetPreviewUIAxes;
                    data = app.dataTestVal{fileIdx, 1}(:, chIdx);
                case 'test-switch'
                    axes = app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    data = app.dataTestSwitch{fileIdx, 1}(:, chIdx);
            end
            
            cla(axes);
            plot(axes, data);
        end

        function updatePreviewData(app, dataset)
            switch dataset
                case 'train-raw'
                    file = app.D_SelectedFileTrainDropDown.Value;
                    [~, fileIdx] = ismember(file, app.D_SelectedFileTrainDropDown.Items);
                    chIdx = str2double(app.D_SelectedChannelTrainDropDown.Value);
                case 'test-raw'
                    file = app.D_SelectedFileTestDropDown.Value;
                    [~, fileIdx] = ismember(file, app.D_SelectedFileTestDropDown.Items);
                    chIdx = str2double(app.D_SelectedChannelTestDropDown.Value);
                case 'train'
                    file = app.P_SelectedFileTrainDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileTrainDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelTrainDropDown.Value);
                case 'test'
                    file = app.P_SelectedFileTestDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileTestDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelTestDropDown.Value);
                case 'test-val'
                    file = app.P_SelectedFileTestValDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileTestValDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelTestValDropDown.Value);
                case 'test-switch'
                    file = app.DS_SelectedFileTestSwitchDropDown.Value;
                    [~, fileIdx] = ismember(file, app.DS_SelectedFileTestSwitchDropDown.Items);
                    chIdx = str2double(app.DS_SelectedChannelTestSwitchDropDown.Value);
            end

            app.plotLoadedData(dataset, fileIdx, chIdx);
            app.createPreviewLegend(dataset, fileIdx);
        end

        function updateCompTimePlot(app)
            cla(app.DE_CompTimeUIAxes);
            if isempty(app.compTimeMetrics)
                xticks(app.DE_CompTimeUIAxes, []);
                return;
            end

            idxMetrics = [];
            for i = 1:length(app.compTimeMetrics)                
                idxMetrics(i) = find(app.fullScores{:, 1} == string(app.compTimeMetrics(i)));
            end

            elapsed = app.fullScores{1, 2:end};
            idxElapsed = 2:(size(app.fullScores, 2));
            idxNaN = isnan(elapsed);
            idxElapsed(idxNaN) = [];
            elapsed(idxNaN) = [];

            [elapsed, idxSort] = sort(elapsed);
            idxElapsed = idxElapsed(idxSort);

            if length(elapsed) > 1
                for i = 1:length(elapsed) - 1
                    if elapsed(i + 1) == elapsed(i)
                        elapsed(i + 1) = elapsed(i + 1) + 0.0001;
                    end
                end
            end

            cla(app.DE_CompTimeUIAxes)

            if ~isempty(app.compTimeMetrics)
                hold(app.DE_CompTimeUIAxes, "on");
                for i = 1:length(app.compTimeMetrics)
                    plot(app.DE_CompTimeUIAxes, elapsed, app.fullScores{idxMetrics(i), idxElapsed}, '.', MarkerSize=5);
                end
                legend(app.DE_CompTimeUIAxes, app.compTimeMetrics, Location="northwest");                
                xticks(app.DE_CompTimeUIAxes, elapsed);
                xticklabels(app.DE_CompTimeUIAxes, app.fullScores.Properties.VariableNames(idxElapsed));
            end
        end
                
        function runPreprocessing(app, preprocessingEnabled)
            if ~app.info_dataLoaded
                return;
            end
           

            if preprocessingEnabled
                switch app.P_MethodButtonGroup.SelectedObject
                    case app.P_RescaleButton
                        preprocMethod = 'Rescale [0, 1]';
                    case app.P_StandardizeButton
                        preprocMethod = 'Standardize';
                    case app.P_RawDataButton
                        preprocMethod = 'Raw Data';
                end
    
                [app.dataTrain, app.allTestData, ~] = preprocessData(app.dataTrainRaw, app.dataTestRaw, preprocMethod, false, []);
            end


             if ~strcmp(app.P_Choice0fAugmentationListBox.Value,'none')

                [app.dataTrain, app.allTestData] = augmentationData(app.dataTrainRaw, app.dataTestRaw, app.P_Choice0fAugmentationListBox.Value,app.P_intensitySlider.Value,app.P_AugmentedTrainingCheckBock.Value);

            end


            if app.P_SplitTestSetForDynamicSwitchCheckBox.Value && app.info_numTestFiles > 1
                ratioSwitch = app.P_RatioTestSwitchEditField.Value;
                [app.dataTestAll, app.labelsTestAll, app.filesTestAll, ...
                    app.dataTestSwitch, app.labelsTestSwitch, app.filesTestSwitch] = splitDatasetForSwitch(app.allTestData, app.allTestLabels, app.allTestFiles, ratioSwitch);
            else
                app.dataTestAll = app.allTestData;
                app.labelsTestAll = app.allTestLabels;
                app.filesTestAll = app.allTestFiles;

                app.dataTestSwitch = [];
                app.labelsTestSwitch = [];
                app.filesTestSwitch = [];
            end

            if app.P_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.P_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end


            [app.dataTest, ...
                app.labelsTest, ...
                app.filesTest, ...
                app.dataTestVal, ...
                app.labelsTestVal, ...                    
                app.filesTestVal] = splitTestVal(app.dataTestAll, app.labelsTestAll, app.filesTestAll, ratioTestVal);

            if ~isempty(app.filesTrain)
                app.P_SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.filesTest)
                app.P_SelectedFileTestDropDown.Items = app.filesTest;
            end

            if ~isempty(app.filesTestVal)
                app.P_SelectedFileTestValDropDown.Items = app.filesTestVal;
            else
                app.P_SelectedFileTestValDropDown.Items = {};
            end

            if ~isempty(app.filesTestSwitch)
                app.DS_SelectedFileTestSwitchDropDown.Items = app.filesTestSwitch;
            else
                app.DS_SelectedFileTestSwitchDropDown.Items = {};
            end

            app.DE_FaultyDataSelectionDropDown.Items = app.filesTest;
            app.SD_FaultyDataSelectionDropDown.Items = app.filesTest;
            
            if ~isempty(app.dataTrain)
                app.updatePreviewData('train');
            end

            if ~isempty(app.dataTest)
                app.updatePreviewData('test');
            end

            if ~isempty(app.dataTestVal)
                app.P_SelectedChannelTestValDropDown.Enable = 'on';
                app.P_SelectedFileTestValDropDown.Enable = 'on';

                app.updatePreviewData('test-val');
            else
                app.P_SelectedChannelTestValDropDown.Enable = 'off';
                app.P_SelectedFileTestValDropDown.Enable = 'off';

                cla(app.P_PreprocessedTestValDatasetPreviewUIAxes);
            end

            if ~isempty(app.dataTestSwitch)
                app.DynamicSwitchPanel.Enable = 'on';

                app.updatePreviewData('test-switch');
            else
                app.DynamicSwitchPanel.Enable = 'off';

                cla(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes);
            end


        end

%% Training functions
        
function trainDNNs(app, models)
            if app.MT_DNN_TrainingPlotsCheckBox.Value
                trainingPlots = 'training-progress';
            else
                trainingPlots = 'none';
            end

            if app.MT_DNN_ParallelCheckBox.Value && app.info_numChannels == 1
                trainedModels_tmp = trainModels_DNN_Parallel(models, ...
                    app.dataTrain, ...
                    app.labelsTrain, ...
                    app.dataTestVal, ...
                    app.labelsTestVal, ...
                    app.settings_thresholds, ...
                    false);
            else
                trainedModels_tmp = trainModels_DNN_Consecutive(models, ...
                    app.dataTrain, ...
                    app.labelsTrain, ...
                    app.dataTestVal, ...
                    app.labelsTestVal, ...
                    app.settings_thresholds, ...
                    trainingPlots, ...
                    app.MT_DNN_ParallelCheckBox.Value);
            end
                
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();
        end


        function trainCMLs(app, models)
            trainedModels_tmp = trainModels_CML(models, ...
                app.dataTrain, ...
                app.labelsTrain, ...
                app.dataTestVal, ...
                app.labelsTestVal, ...
                app.settings_thresholds);
            
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end
            
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();
        end

       
        function trainSs(app,models)
            trainedModels_tmp = trainModels_S(models, ...
                app.dataTrain, ...
                app.labelsTrain, ...
                app.dataTestVal, ...
                app.labelsTestVal, ...
                app.settings_thresholds);
            
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end
            
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Detection and evaluation functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% General functions
                           
        function plotDynamicThresholdPrediction(app, anomalyScores, predictedLabels, labels, dataY, threshold, windowSize, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
            app.DE_PlotsAnomalyScoresUIAxes.Title.String  = "Anomaly Scores";
            
            windowSize = max(1, floor(length(anomalyScores) * (windowSize / 100)));

            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_PlotsDataUIAxes, dataY(:, 1));
            
            threshY = [];
            threshX = [];
            for i = 1:length(threshold)
                threshY(i,:) = ones(1, windowSize) * threshold(i);
                threshX(i,:) = ((i - 1) * windowSize + 1):(windowSize * i);
            end

            time = 1:length(anomalyScores);
            indices = getIndexes(predictedLabels);
            trueIndices = getIndexes(labels);
            
            YLims = get(app.DE_PlotsDataUIAxes, 'YLim');
            limit = [];
            trueLims = [];
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limit)
                patch(app.DE_PlotsDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_PlotsDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            end

            try
                legend(app.DE_PlotsDataUIAxes, 'data', "Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_PlotsDataUIAxes, 'data', "Detected Anomalies")
            end
            
            plot(app.DE_PlotsAnomalyScoresUIAxes, time, anomalyScores)
            hold(app.DE_PlotsAnomalyScoresUIAxes, "on")
            plot(app.DE_PlotsAnomalyScoresUIAxes, threshX', threshY', 'Color', 'red')
            hold(app.DE_PlotsAnomalyScoresUIAxes, "off")
            set(app.DE_PlotsAnomalyScoresUIAxes, 'XLim', [0 length(anomalyScores)])
            
            Types = ["Point-wise"; "Event-wise"; "Point-adjusted"; "Composite"];
            Precisions = [scoresPointwise(1); scoresEventwise(1); scoresPointAdjusted(1); NaN];
            Recalls = [scoresPointwise(2); scoresEventwise(2); scoresPointAdjusted(2); NaN];
            F1Scores = [scoresPointwise(3); scoresEventwise(3); scoresPointAdjusted(3); scoresComposite(1)];
            F05Scores = [scoresPointwise(4); scoresEventwise(4); scoresPointAdjusted(4); scoresComposite(2)];

            app.DE_PlotsScoresUITable.Data = table(Types, Precisions, Recalls, F1Scores, F05Scores);
        end

        function plotStaticThresholdPrediction(app, anomalyScores, predictedLabels, labels, dataY, threshold, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
            app.DE_PlotsAnomalyScoresUIAxes.Title.String  = "Anomaly Scores";

            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_PlotsDataUIAxes, dataY(:, 1));

            time = 1:length(anomalyScores);
            indices = getIndexes(predictedLabels);
            trueIndices = getIndexes(labels);

            plot(app.DE_PlotsAnomalyScoresUIAxes, time, anomalyScores)
            yline(app.DE_PlotsAnomalyScoresUIAxes, threshold, 'r')

            limit = [];
            YLims = get(app.DE_PlotsDataUIAxes, 'YLim');
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            
            if ~isempty(indices) && ~isempty(limit)
                patch(app.DE_PlotsDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_PlotsDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            end

            try
                legend(app.DE_PlotsDataUIAxes, 'data',"Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_PlotsDataUIAxes, 'data', "Detected anomalies")
            end
            
            set(app.DE_PlotsAnomalyScoresUIAxes, 'XLim', [0 length(anomalyScores)])
            set(app.DE_PlotsDataUIAxes, 'XLim', [0 length(anomalyScores)])
            
            Types = ["Point-wise"; "Event-wise"; "Point-adjusted"; "Composite"];
            Precisions = [scoresPointwise(1); scoresEventwise(1); scoresPointAdjusted(1); NaN];
            Recalls = [scoresPointwise(2); scoresEventwise(2); scoresPointAdjusted(2); NaN];
            F1Scores = [scoresPointwise(3); scoresEventwise(3); scoresPointAdjusted(3); scoresComposite(1)];
            F05Scores = [scoresPointwise(4); scoresEventwise(4); scoresPointAdjusted(4); scoresComposite(2)];

            app.DE_PlotsScoresUITable.Data = table(Types, Precisions, Recalls, F1Scores, F05Scores);
        end
        
        function plotPredictedLabels(app, predictedLabels, labels, dataY, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
            app.DE_PlotsAnomalyScoresUIAxes.Title.String = "Predicted Labels";

            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_PlotsDataUIAxes, dataY(:, 1));

            time = 1:length(predictedLabels);
            indices = getIndexes(predictedLabels);
            trueIndices = getIndexes(labels);

            plot(app.DE_PlotsAnomalyScoresUIAxes, time, predictedLabels)

            limit = [];
            YLims = get(app.DE_PlotsDataUIAxes, 'YLim');
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            
            if ~isempty(indices) && ~isempty(limit)
                patch(app.DE_PlotsDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_PlotsDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            end

            try
                legend(app.DE_PlotsDataUIAxes, 'data',"Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_PlotsDataUIAxes, 'data', "Detected anomalies")
            end
            
            set(app.DE_PlotsAnomalyScoresUIAxes, 'XLim', [0 length(predictedLabels)])
            set(app.DE_PlotsDataUIAxes, 'XLim', [0 length(predictedLabels)])
            
            Types = ["Point-wise"; "Event-wise"; "Point-adjusted"; "Composite"];
            Precisions = [scoresPointwise(1); scoresEventwise(1); scoresPointAdjusted(1); NaN];
            Recalls = [scoresPointwise(2); scoresEventwise(2); scoresPointAdjusted(2); NaN];
            F1Scores = [scoresPointwise(3); scoresEventwise(3); scoresPointAdjusted(3); scoresComposite(1)];
            F05Scores = [scoresPointwise(4); scoresEventwise(4); scoresPointAdjusted(4); scoresComposite(2)];

            app.DE_PlotsScoresUITable.Data = table(Types, Precisions, Recalls, F1Scores, F05Scores);
        end

        function updateFullScoreTable(app, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite, modelId)
            compTime = app.fullScores.(modelId)(1);
            app.fullScores.(modelId) = [compTime; ...
                                            scoresPointwise(3); ...
                                            scoresEventwise(3); ...
                                            scoresPointAdjusted(3); ...
                                            scoresComposite(1); ...
                                            scoresPointwise(4); ...
                                            scoresEventwise(4); ...
                                            scoresPointAdjusted(4); ...
                                            scoresComposite(2); ...
                                            scoresPointwise(1); ...
                                            scoresEventwise(1); ...
                                            scoresPointAdjusted(1); ...
                                            scoresPointwise(2); ...
                                            scoresEventwise(2); ...
                                            scoresPointAdjusted(2)];
        end

        function updateCompTime(app, compTime, modelId)
            scores = app.fullScores.(modelId)(2:end);
            app.fullScores.(modelId) = [compTime; scores];
        end
        
        function updateDetection(app, options, anomalyScores, dataY, labels, selectedThreshold, modelId, plotEnabled)
            if ~options.outputsLabels
                if isstring(selectedThreshold) && strcmp(selectedThreshold, "dynamic")
                    [predictedLabels, dynamicThreshold] = calcDynamicThresholdPrediction(anomalyScores, labels, app.settings_dynamicThreshold);
    
                    [scoresPointwise, scoresEventwise, ...
                        scoresPointAdjusted, scoresComposite] = calcScores(predictedLabels, labels);
    
                    if plotEnabled
                        app.plotDynamicThresholdPrediction(anomalyScores, predictedLabels, labels, dataY, dynamicThreshold, app.settings_dynamicThreshold.windowSize, ...
                            scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
                    end
                else
                    [predictedLabels, staticThreshold] = calcStaticThresholdPrediction(anomalyScores, labels, selectedThreshold, options.model);
    
                    [scoresPointwise, scoresEventwise, ...
                        scoresPointAdjusted, scoresComposite] = calcScores(predictedLabels, labels);
    
                    if plotEnabled
                        app.plotStaticThresholdPrediction(anomalyScores, predictedLabels, labels, dataY, staticThreshold, ...
                            scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
    
                    end
                end
            else
                predictedLabels = anomalyScores;
                [scoresPointwise, scoresEventwise, ...
                        scoresPointAdjusted, scoresComposite] = calcScores(predictedLabels, labels);
    
                if plotEnabled
                    app.plotPredictedLabels(predictedLabels, labels, dataY, ...
                        scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
                end
            end

            app.updateFullScoreTable(scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite, modelId);
        end
        
        function runDetectionFor(app, models)
            % Main detection function
            % Loops over all models and runs the detection accordingly
            % Input: models - struct with fields: type, name
            %                 type: One of DNN, CML Novelty, CML Outlier, S
            %                 name: id for the model which corresponds to a
            %                       fieldname in the trainedModels struct
            %                       Example: Hybrid_CNN_LSTM_1
            if isempty(models)
                uialert(app.UIFigure, "No Models (selected).", ...
                                'Detection Error');
                return;
            end

            selectedItem = app.DE_FaultyDataSelectionDropDown.Value;
            [~, idxData] = ismember(selectedItem, app.DE_FaultyDataSelectionDropDown.Items);

            numModels = numel(models);

            for i = 1:numModels
                modelId = models(i).id;
                type = models(i).type;

                trainedModel = app.trainedModels.(modelId);

                testingData = app.dataTest(idxData, 1);
                testingLabels = app.labelsTest(idxData, 1);

                % Run detection according to type of model (DNN, CML, S)
                switch type
                    case 'DNN'
                        [XTest, YTest, labels] = prepareDataTest_DNN_wrapper(trainedModel.options, testingData, testingLabels);
                        
                        [anomalyScores, compTime] = detectWithDNN_wrapper(trainedModel, XTest, YTest, labels, true);
                    case 'CML'
                        [XTest, YTest, labels] = prepareDataTest_CML_wrapper(trainedModel.options, testingData, testingLabels);

                        anomalyScores = detectWithCML_wrapper(trainedModel, XTest, YTest, labels);

                        compTime = NaN;
                    case 'S'
                        [XTest, YTest, labels] = prepareDataTest_S_wrapper(trainedModel.options, testingData, testingLabels);

                        anomalyScores = detectWithS_wrapper(trainedModel, XTest, YTest, labels);

                        compTime = NaN;
                end

                app.updateCompTime(compTime, modelId);
    
                if i ~= numModels
                    % Run detection and save scores
                    if ~isempty(trainedModel.staticThreshold) && isfield(trainedModel.staticThreshold, app.currentDetection_threshold)
                        selectedThreshold = trainedModel.staticThreshold.(app.currentDetection_threshold);
                    else
                        selectedThreshold = app.currentDetection_threshold;
                    end
    
                    app.updateDetection(trainedModel.options, anomalyScores, YTest, labels, ...
                        selectedThreshold, modelId, false);
                else
                    % If plot is enabled, run detection, save scores and plot 
                    % results in uifigures                    
                    if ~isempty(trainedModel.staticThreshold) && isfield(trainedModel.staticThreshold, app.currentDetection_threshold)
                        selectedThreshold = trainedModel.staticThreshold.(app.currentDetection_threshold);
                    else
                        selectedThreshold = app.currentDetection_threshold;
                    end
    
                    app.updateDetection(trainedModel.options, anomalyScores, YTest, labels, ...
                        selectedThreshold, modelId, true);
        
                    app.currentDetection_anomalyScores = anomalyScores;
                    app.currentDetection_YTest = YTest;
                    app.currentDetection_labels = labels;
                    app.currentDetection_id = modelId;
                    app.currentDetection_type = type;
                    app.currentDetection_options = trainedModel.options;
        
                    currentModelLabel = trainedModel.options.label;
                    app.DE_DetectionShownForLabel.Text = sprintf('Detection Shown for: %s', currentModelLabel);
                end
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();
        end

        function showTrainedModels(app)
            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.ItemsData = [];
            app.SD_TrainedModelsListBox.Items = {};
            app.SD_TrainedModelsListBox.ItemsData = [];

            if isempty(app.trainedModels)
                % If no models are loaded or trained
                return;
            end
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                for i = 1:numel(fields)
                    label = app.trainedModels.(fields{i}).options.label;

                    modelInfo.type = app.trainedModels.(fields{i}).options.type;
                    modelInfo.id = fields{i};

                    app.DE_TrainedModelsListBox.Items = [app.DE_TrainedModelsListBox.Items ...
                        label];
                    app.DE_TrainedModelsListBox.ItemsData = [app.DE_TrainedModelsListBox.ItemsData ...
                        modelInfo];

                    if strcmp(modelInfo.type, 'DNN')
                        app.SD_TrainedModelsListBox.Items = [app.SD_TrainedModelsListBox.Items ...
                            label];
                        app.SD_TrainedModelsListBox.ItemsData = [app.SD_TrainedModelsListBox.ItemsData ...
                            modelInfo];
                    end
                end
            end
        end
        
        function sortTrainedModels(app)
            sortBy = app.DE_SortByDropDown.Value;
            [~, rowIdx] = ismember(sortBy, app.fullScores{:, 1});

            numOfNets = length(app.DE_TrainedModelsListBox.ItemsData);
            values = zeros(1, numOfNets);
            for i = 1:numOfNets
                fieldName = app.DE_TrainedModelsListBox.ItemsData(i).id;
                modelScores = app.fullScores.(fieldName);

                values(i) = modelScores(rowIdx, 1);
                if isnan(values(i))
                    values(i) = 0;
                end
            end

            [~, idxs] = sort(values, 'descend');

            sortedItems = app.DE_TrainedModelsListBox.Items(idxs);
            sortedItemsData = app.DE_TrainedModelsListBox.ItemsData(idxs);
            
            for i = 1:length(sortedItems)
                sortedItems{i} = sprintf('%d. %s', i, sortedItems{i});
            end

            app.DE_TrainedModelsListBox.Items = sortedItems;
            app.DE_TrainedModelsListBox.ItemsData = sortedItemsData;
        end
     
        function showScoresInTable(app)
            app.DE_FullDetectionUITable.Data = {};
            if ~isempty(app.fullScores)
                app.DE_FullDetectionUITable.Data = app.fullScores;
                app.DE_FullDetectionUITable.ColumnName = app.fullScores.Properties.VariableNames;

                
                if size(app.fullScores, 2) > 1
                    removeStyle(app.DE_FullDetectionUITable);

                    % Add blue background for comp time row
                    styleBlue = uistyle;
                    styleBlue.BackgroundColor = '#97b0e7';
                    addStyle(app.DE_FullDetectionUITable, styleBlue, row=1);

                    % Highlight maxima
                    app.highlightTableMaxima(app.DE_FullDetectionUITable, 'all', 1);
                end
            end
        end

        function path = saveModelForSim(app, Mdl, modelId)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.mat', 'Save model for simulink detection', sprintf('%s.mat', modelId));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end
                        
            fileName = fullfile(path, file);
            assignin('base', 'Mdl', Mdl);
            save(fileName, 'Mdl');
                                
            path = fileName;
        end
    end

    methods (Access = public)

        function addModelToList(app, options)
            [label, id, instanceNumber] = app.getLabelForModel(options.model, options.type);
            options.id = id;
            options.label = label;
            options.instanceNumber = instanceNumber;
            modelInfo.options = options;
            
            switch options.type
                case 'DNN'
                    app.MT_DNN_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items label];
                    app.MT_DNN_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData modelInfo];
                case 'CML'
                    app.MT_CML_ModelsListBox.Items = [app.MT_CML_ModelsListBox.Items label];
                    app.MT_CML_ModelsListBox.ItemsData = [app.MT_CML_ModelsListBox.ItemsData modelInfo];
                case 'S'
                    app.MT_S_ModelsListBox.Items = [app.MT_S_ModelsListBox.Items label];
                    app.MT_S_ModelsListBox.ItemsData = [app.MT_S_ModelsListBox.ItemsData modelInfo];
            end

            app.toggleTrainButtons(options.type, true);
        end

        function convertModelOptionsInList(app, options)            
            switch options.type
                case 'DNN'
                    listBox = app.MT_DNN_ModelsListBox;
                case 'CML'
                    listBox = app.MT_CML_ModelsListBox;
                case 'S'
                    listBox = app.MT_S_ModelsListBox;
            end

            for i = 1:length(listBox.ItemsData)
                if strcmp(listBox.ItemsData(i).options.id, options.id)
                    listBox.ItemsData(i).options = options;
                    return;
                end
            end
        end
    
        function runOptimization(app, options, optVars, iterations, cmpScore, threshold, trainingPlots, oldPointer_2)
            if app.info_optimizationRunning
                uialert(app.UIFigure, 'There is already an optimization in progress!', 'Optimization Running', Icon='error');
                return;
            end
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', 'Optimization Error', Icon='error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                if options.requiresPriorTraining
                    uialert(app.UIFigure, "You are trying to optimize a model but the selected dataset doesn't contain training data", "Optimization Error", Icon='error');
                    return;
                end
            end
            
            app.info_optimizationRunning = true;
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Optimizing Model(s)',...
                Indeterminate='on');
            drawnow;
            
            results = optimizeModel(optVars, options, app.dataTrain, ...
                                    app.labelsTrain, app.dataTestVal, app.labelsTestVal, ...
                                    app.dataTest, app.labelsTest, ...
                                    threshold, app.settings_dynamicThreshold, cmpScore, iterations, ...
                                    trainingPlots, app.MT_DNN_ParallelCheckBox.Value, app.settings_exportLogdata);

            optimumVars = results.XAtMinObjective;
            optimumVarNames = optimumVars.Properties.VariableNames;
            
            % Convert options to optimized version
            options = adaptModelOptions(options, optimumVars);
            
            % Convert options in list
            app.convertModelOptionsInList(options);

            % Train model
            newModel.options = options;
            switch options.type
                case 'DNN'
                    app.trainDNNs(newModel);
                case 'CML'
                    app.trainCMLs(newModel);
                case 'S'
                    app.trainSs(newModel);
            end
            
            msg = sprintf("Successfully optimized %s", options.label);

            close(d);
            uialert(app.UIFigure, msg, 'Optimization finished', Icon='success');
            app.endTask(oldPointer);
            app.info_optimizationRunning = false;

            app.popup_OptimizeParameters.stopTask(oldPointer_2);
        end
        
        function runOptimizationFor(app, iterations, cmpScore, threshold, selection, trainingPlots, oldPointer_2)
            if app.info_optimizationRunning
                uialert(app.UIFigure, 'There is already an optimization in progress!', 'Optimization Running', Icon='error');
                return;
            end
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', 'Optimization Error', Icon='error');
                return;
            end

            switch selection
                case 'DNN_selection'
                    models = app.MT_DNN_ModelsListBox.Value;
                case 'DNN_all'
                    models = app.MT_DNN_ModelsListBox.ItemsData;
                case 'CML_selection'
                    models = app.MT_CML_ModelsListBox.Value;
                case 'CML_all'
                    models = app.MT_CML_ModelsListBox.ItemsData;
                case 'S_selection'
                    models = app.MT_S_ModelsListBox.Value;
                case 'S_all'
                    models = app.MT_S_ModelsListBox.ItemsData;
                case 'all'
                    % Load all models as added on the Training tab
                    models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
                    models_CML = app.MT_CML_ModelsListBox.ItemsData;
                    models_S = app.MT_S_ModelsListBox.ItemsData;
                    
                    models = [];
                    if ~isempty(models_DNN)
                        models = [models models_DNN];
                    end
                    if ~isempty(models_CML)
                        models = [models models_CML];
                    end
                    if ~isempty(models_S)
                        models = [models models_S];
                    end         
            end

            if isempty(models)
                uialert(app.UIFigure, "No models found for optimization.", 'Model Error', Icon='error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to optimize a model but the selected dataset doesn't contain training data", "Optimization Error", Icon='error');
                        return;
                    end
                end
            end
            
            app.info_optimizationRunning = true;
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Auto Optimization',...
                Indeterminate='on');
            drawnow;

            app.runOptimizations(models, iterations, cmpScore, threshold, trainingPlots)

            close(d);
            uialert(app.UIFigure, 'Successfully finished optimization.', 'Optimization Successful', Icon='success');
            app.endTask(oldPointer);
            app.info_optimizationRunning = false;

            app.popup_OptimizeAll.stopTask(oldPointer_2);
        end

        function runOptimizations(app, models, iterations, cmpScore, threshold, trainingPlots)
            configOptFileName = 'tsad_platform_config_optimization.json';

            bestOptions = autoOptimization(models, app.dataTrain, app.labelsTrain, ...
                                                app.dataTestVal, app.labelsTestVal, app.dataTest, ...
                                                app.labelsTest, configOptFileName, ...
                                                cmpScore, threshold, app.settings_dynamicThreshold, iterations, ...
                                                trainingPlots, app.MT_DNN_ParallelCheckBox.Value, app.settings_exportLogdata);
            
            % Convert models to optimized versions in lists        
            if isfield(bestOptions, "DNN_Models")
                for i = 1:length(bestOptions.DNN_Models)
                    app.convertModelOptionsInList(bestOptions.DNN_Models(i).options);
                end
            end
            if isfield(bestOptions, "CML_Models")
                for i = 1:length(bestOptions.CML_Models)
                    app.convertModelOptionsInList(bestOptions.CML_Models(i).options);
                end
            end
            if isfield(bestOptions, "S_Models")
                for i = 1:length(bestOptions.S_Models)
                    app.convertModelOptionsInList(bestOptions.S_Models(i).options);
                end
            end

            % Train optimized models
            if isfield(bestOptions, "DNN_Models")
                if ~isempty(bestOptions.DNN_Models)
                    app.trainDNNs(bestOptions.DNN_Models);
                end
            end
            if isfield(bestOptions, "CML_Models")
                if ~isempty(bestOptions.CML_Models)
                    app.trainCMLs(bestOptions.CML_Models);
                end
            end
            if isfield(bestOptions, "S_Models")
                if ~isempty(bestOptions.S_Models)
                    app.trainSs(bestOptions.S_Models);
                end
            end
        end
    
        function updateThresholds(app, newThresholds)
            app.settings_thresholds = newThresholds;
            items = getThresholdLabels(newThresholds);
            app.DS_ThresholdDropDown.Items = items;
            app.DE_ThresholdDropDown.Items = items;

            [flag, idx] = ismember("Dynamic", items);
            if flag
                items(idx) = [];
            end
            app.SD_ThresholdDropDown.Items = items;
        end
        
        function updateExportLogdata(app, exportLogdata)
            app.settings_exportLogdata = exportLogdata;
        end

        function updateCompTimeMetrics(app, newMetrics)
            app.compTimeMetrics = newMetrics;

            if app.info_detectionRun
                app.updateCompTimePlot();
            end
        end

        function updateDynamicThresholdSettings(app, newOptions)
            app.settings_dynamicThreshold = newOptions;

            app.DE_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.DE_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.DE_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn off axes for model preview
            axis(app.MT_PreviewUIAxes, 'off')
            
            % Init score table
            app.fullScores.Properties.VariableNames = "Metric";
            
            % Init threshold dropdown items
            items = getThresholdLabels(app.settings_thresholds);
            app.DS_ThresholdDropDown.Items = items;
            app.DE_ThresholdDropDown.Items = items;
            [flag, idx] = ismember("Dynamic", items);
            if flag
                items(idx) = [];
            end
            app.SD_ThresholdDropDown.Items = items;

            app.settings_dynamicThreshold.windowSize = 50;
            app.settings_dynamicThreshold.anomalyPadding = 30;
            app.settings_dynamicThreshold.zRange = 5;
            app.settings_dynamicThreshold.minPercent = 1;

            app.DE_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.DE_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.DE_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;

            Types = ["Point-wise"; "Event-wise"; "Point-adjusted"; "Composite"];
            Precisions = [NaN; NaN; NaN; NaN];
            Recalls = [NaN; NaN; NaN; NaN];
            F1Scores = [NaN; NaN; NaN; NaN];
            F05Scores = [NaN; NaN; NaN; NaN];

            app.DE_PlotsScoresUITable.Data = table(Types, Precisions, Recalls, F1Scores, F05Scores);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.popup_ModelSelection_DNN)
                app.popup_ModelSelection_DNN.delete()
            end
            if ~isempty(app.popup_ModelSelection_CML)
                app.popup_ModelSelection_CML.delete()
            end
            if ~isempty(app.popup_ModelSelection_S)
                app.popup_ModelSelection_S.delete()
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete()
            end
            if ~isempty(app.popup_OptimizeAll)
                app.popup_OptimizeAll.delete()
            end
            if ~isempty(app.popup_OptimizationSettings)
                app.popup_OptimizationSettings.delete()
            end
            if ~isempty(app.popup_ThresholdSelection)
                app.popup_ThresholdSelection.delete()
            end
            if ~isempty(app.popup_DynamicThresholdSettings)
                app.popup_DynamicThresholdSettings.delete()
            end
            if ~isempty(app.popup_CompTimeMetricSelection)
                app.popup_CompTimeMetricSelection.delete()
            end
            if ~isempty(gcp('nocreate'))
                delete(gcp('nocreate'));
            end
            if ~isempty(app.popup_VisualizationData)
                app.popup_VisualizationData.delete()
            end
            
            app.delete()
        end

        % Menu selected function: ThresholdSelectionMenu
        function ThresholdSelectionMenuSelected(app, event)
            app.popup_ThresholdSelection = ThresholdSelection(app, app.settings_thresholds);
        end

        % Menu selected function: DynamicThresholdMenu
        function DynamicThresholdMenuSelected(app, event)
            app.popup_DynamicThresholdSettings = DynamicThresholdSettings(app, app.settings_dynamicThreshold);
        end

        % Menu selected function: OptimizationMenu
        function OptimizationMenuSelected(app, event)
            app.popup_OptimizationSettings = OptimizationSettings(app, app.settings_exportLogdata);
        end

        % Button pushed function: D_BrowseCustomDatasetButton
        function D_BrowseCustomDatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.D_ImportCustomDatasetEditField.Value = path;
            end
        end

        % Button pushed function: D_LoadDataButton
        function D_LoadDataButtonPushed(app, event)
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Loading Dataset',...
                Indeterminate='on');
            drawnow;

            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestValDatasetPreviewUIAxes);
            cla(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes);
            
            app.resetAllData();

            app.info_dataLoaded = false;

            [app.dataTrainRaw, ...
                app.timestampsTrain, ...
                app.labelsTrain, ...
                app.filesTrain, ...
                app.dataTestRaw, ...
                app.allTestTimestamps, ...
                app.allTestLabels, ...
                app.allTestFiles] = loadCustomDataset(app.D_ImportCustomDatasetEditField.Value);

            app.info_numTrainFiles = numel(app.filesTrain);
            app.info_numTestFiles = numel(app.allTestFiles);

            if app.info_numTestFiles < 2
                app.P_SplitTestSetForDynamicSwitchCheckBox.Enable = 'off';
                app.P_RatioTestSwitchEditField.Enable = 'off';
                app.P_RatioTestSwitchLabel.Enable = 'off';
                app.P_SplitTestSetForDynamicSwitchCheckBox.Value = false;
                app.toggleDynamicSwitchPanel(false);
            else
                app.P_SplitTestSetForDynamicSwitchCheckBox.Enable = 'on';
                if app.P_SplitTestSetForDynamicSwitchCheckBox.Value
                    app.P_RatioTestSwitchEditField.Enable = 'on';
                    app.P_RatioTestSwitchLabel.Enable = 'on';
                    app.toggleDynamicSwitchPanel(true);
                else
                    app.P_RatioTestSwitchEditField.Enable = 'off';
                    app.P_RatioTestSwitchLabel.Enable = 'off';
                    app.toggleDynamicSwitchPanel(false);
                end
            end

            if isempty(app.dataTestRaw) && isempty(app.dataTrainRaw)
                close(d);
                uialert(app.UIFigure, ['No testing or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: train, test. The folders should include .csv files with fields:' ...
                    'timestamp, value1, value2, ..., is_anomaly'], ...
                            'Data Error');
                app.endTask(oldPointer);
                return;
            end

            if ~isempty(app.dataTrainRaw)
                app.plotLoadedData('train-raw', 1, 1);
                app.createPreviewLegend('train-raw', 1);

                app.info_numChannels = size(app.dataTrainRaw{1, 1}, 2);

                app.D_SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.dataTestRaw)
                app.plotLoadedData('test-raw', 1, 1);
                app.createPreviewLegend('test-raw', 1);

                app.info_numChannels = size(app.dataTestRaw{1, 1}, 2);

                app.D_SelectedFileTestDropDown.Items = app.allTestFiles;
            end
            
            app.info_dataLoaded = true;
            
            app.showDatasetParameters();
            app.runPreprocessing(true);

            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: D_SelectedFileTrainDropDown
        function D_SelectedFileTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train-raw');
        end

        % Value changed function: D_SelectedChannelTrainDropDown
        function D_SelectedChannelTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train-raw');
        end

        % Value changed function: D_SelectedFileTestDropDown
        function D_SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
        end

        % Value changed function: D_SelectedChannelTestDropDown
        function D_SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
        end

        % Button pushed function: D_NextButton
        function D_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Selection changed function: P_MethodButtonGroup
        function P_MethodButtonGroupSelectionChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(true);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_SplitTestSetForDynamicSwitchCheckBox
        function P_SplitTestSetForDynamicSwitchCheckBoxValueChanged(app, event)
            value = app.P_SplitTestSetForDynamicSwitchCheckBox.Value;
            app.toggleDynamicSwitchPanel(value);
            
            if value
                app.P_RatioTestSwitchLabel.Enable = 'on';
                app.P_RatioTestSwitchEditField.Enable = 'on';
            else
                app.P_RatioTestSwitchLabel.Enable = 'off';
                app.P_RatioTestSwitchEditField.Enable = 'off';
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_RatioTestSwitchEditField
        function P_RatioTestSwitchEditFieldValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_UseAnomalousValidationSetCheckBox
        function P_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.P_UseAnomalousValidationSetCheckBox.Value
                app.P_RatioTestValLabel.Enable = 'on';
                app.P_RatioTestValEditField.Enable = 'on';
            else
                app.P_RatioTestValLabel.Enable = 'off';
                app.P_RatioTestValEditField.Enable = 'off';
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;

            app.runPreprocessing(false);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_RatioTestValEditField
        function P_RatioTestValEditFieldValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
            app.endTask(oldPointer);            
        end

        % Value changed function: P_SelectedFileTrainDropDown
        function P_SelectedFileTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train');
        end

        % Value changed function: P_SelectedChannelTrainDropDown
        function P_SelectedChannelTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train');
        end

        % Value changed function: P_SelectedFileTestDropDown
        function P_SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
        end

        % Value changed function: P_SelectedChannelTestDropDown
        function P_SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
        end

        % Value changed function: P_SelectedFileTestValDropDown
        function P_SelectedFileTestValDropDownValueChanged(app, event)
            app.updatePreviewData('test-val');
        end

        % Value changed function: P_SelectedChannelTestValDropDown
        function P_SelectedChannelTestValDropDownValueChanged(app, event)
            app.updatePreviewData('test-val');
        end

        % Button pushed function: P_PreviousButton
        function P_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.DataTab;
        end

        % Value changed function: P_intensitySlider
        function P_intensitySliderValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;

            app.runPreprocessing(true);

            close(d);
            app.endTask(oldPointer);
            
        end

        % Value changed function: P_Choice0fAugmentationListBox
        function P_Choice0fAugmentationListBoxValueChanged(app, event)
            value = app.P_Choice0fAugmentationListBox.Value;

            %changing the visual of the slider depending on the transformation chosen

            switch value
                case 'none'
                    app.P_intensitySlider.Visible='off';
                    app.intensitySliderLabel.Visible='off';

                case 'white noise'
                    app.P_intensitySlider.Limits = [0, 1];
                    app.intensitySliderLabel.Text = 'Standard deviation';
                    app.P_intensitySlider.Visible='on';
                    app.intensitySliderLabel.Visible='on';

                case 'random walk'
                    app.P_intensitySlider.Limits = [0, 1];
                    app.intensitySliderLabel.Text = 'Standard deviation';
                    app.P_intensitySlider.Visible='on';
                    app.intensitySliderLabel.Visible='on';
                
                case 'global shift'
                    app.P_intensitySlider.Limits = [-100, 100];
                    app.intensitySliderLabel.Text = 'Shift of the % of the max value';
                    app.P_intensitySlider.Visible='on';
                    app.intensitySliderLabel.Visible='on';

                case 'attenuate extremum'
                    app.P_intensitySlider.Limits = [0, 100];
                    app.intensitySliderLabel.Text = 'Strength of the transformation in %';
                    app.P_intensitySlider.Visible='on';
                    app.intensitySliderLabel.Visible='on';
                    
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow; 

            app.runPreprocessing(true);

            close(d);
            app.endTask(oldPointer);


        end

        % Value changed function: P_AugmentedTrainingCheckBock
        function P_AugmentedTrainingCheckBockValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;          
            
            app.runPreprocessing(true);

            close(d);
            app.endTask(oldPointer);
            
        end

        % Button pushed function: P_NextButton
        function P_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.ModelTrainingTab;
        end

        % Callback function
        function MT_NetworkSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MT_NetworkSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MT_NewNetworksButton
                    app.MT_PretrainedNetworkLabel.Enable = 'off';
                    app.MT_PretrainedNetworkTextArea.Enable = 'off';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'off';
                    app.MT_NetworksLabel.Enable = 'on';
                    app.MT_DNN_ModelsListBox.Enable = 'on';
                    app.MT_DNN_AddModelsButton.Enable = 'on';
                    app.MT_DNN_ClearModelsButton.Enable = 'on';
                case app.MT_PretrainedNetworkButton
                    app.MT_PretrainedNetworkLabel.Enable = 'on';
                    app.MT_PretrainedNetworkTextArea.Enable = 'on';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'on';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'on';
                    app.MT_NetworksLabel.Enable = 'off';
                    app.MT_DNN_ModelsListBox.Enable = 'off';
                    app.MT_DNN_AddModelsButton.Enable = 'off';
                    app.MT_DNN_ClearModelsButton.Enable = 'off';
            end
        end

        % Button pushed function: MT_LoadConfigFromFileButton
        function MT_LoadConfigFromFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uigetfile('*.json', ...
               'Select a configuration file', pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                fid = fopen(filePath);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);                
                models = jsondecode(str);
            
                app.MT_DNN_ModelsListBox.Items = {};
                app.MT_DNN_ModelsListBox.ItemsData = [];
                app.MT_CML_ModelsListBox.Items = {};
                app.MT_CML_ModelsListBox.ItemsData = [];
                app.MT_S_ModelsListBox.Items = {};
                app.MT_S_ModelsListBox.ItemsData = [];
                
                if isfield(models, 'DNN_Models')
                    for i = 1:length(models.DNN_Models)
                        app.addModelToList(models.DNN_Models(i).options);
                    end
                end
                if isfield(models, 'CML_Models')
                    for i = 1:length(models.CML_Models)
                        app.addModelToList(models.CML_Models(i).options);
                    end
                end
                if isfield(models, 'S_Models')
                    for i = 1:length(models.S_Models)
                        app.addModelToList(models.S_Models(i).options);
                    end
                end
            end            
        end

        % Button pushed function: MT_ExportConfigForSelectionButton
        function MT_ExportConfigForSelectionButtonPushed(app, event)
            DNN_models = app.MT_DNN_ModelsListBox.Value;
            CML_models = app.MT_CML_ModelsListBox.Value;
            S_models = app.MT_S_ModelsListBox.Value;
            
            if isempty(DNN_models) && isempty(CML_models) && isempty(S_models)
                uialert(app.UIFigure, "No models selected for export.", 'Export Error');
                return;
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.json', 'File Selection', sprintf('tsad_platform_config_%s.json', datestr(now,'mm-dd-yyyy_HH-MM')));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end

            if ~isempty(DNN_models)
                allModels.DNN_Models = DNN_models;
            end
            if ~isempty(CML_models)
                allModels.CML_Models = CML_models;
            end
            if ~isempty(S_models)
                allModels.S_Models = S_models;
            end
            
            filePath = fullfile(path, file);
            fid = fopen(filePath, 'w');
            fprintf(fid, '%s', jsonencode(allModels, PrettyPrint=true));
            fclose(fid);

            uialert(app.UIFigure, 'Successfully saved config.', 'Save Successful', Icon='success');
        end

        % Button pushed function: MT_DNN_AddModelsButton
        function MT_DNN_AddModelsButtonPushed(app, event)
            app.popup_ModelSelection_DNN = ModelSelection_DNN(app);
        end

        % Button pushed function: MT_DNN_AddAllModelsButton
        function MT_DNN_AddAllModelsButtonPushed(app, event)
            app.removeDetection(app.MT_DNN_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.ItemsData = []; 

            % Load all models with their configuration
            fid = fopen('tsad_platform_config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_DNN = models_All.DNN_Models;
            
            if ~isempty(models_DNN)
                for i = 1:length(models_DNN)
                    app.addModelToList(models_DNN(i).options);
                end
            end

            app.toggleTrainButtons('DNN', true);
        end

        % Button pushed function: MT_DNN_ClearModelsButton
        function MT_DNN_ClearModelsButtonPushed(app, event)
            app.removeDetection(app.MT_DNN_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.ItemsData = [];

            app.toggleTrainButtons('DNN', false);
        end

        % Menu selected function: MT_DNN_ShowModelParametersMenu
        function MT_DNN_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_DNN_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_DNN_DeleteMenu
        function MT_DNN_DeleteMenuSelected(app, event)
            selectedItems = app.MT_DNN_ModelsListBox.Value;
            
            app.removeDetection(selectedItems);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_DNN_ModelsListBox.Items);
                app.MT_DNN_ModelsListBox.Items(idx) = [];
                app.MT_DNN_ModelsListBox.ItemsData(idx) = [];
            end

            if isempty(app.MT_DNN_ModelsListBox.Items)
                app.toggleTrainButtons('DNN', false)
            end
        end

        % Button pushed function: MT_DNN_TrainSelectedModelsButton
        function MT_DNN_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_DNN_ModelsListBox.Value;

            if isempty(app.MT_DNN_ModelsListBox.Value)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;

            app.trainDNNs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_DNN_TrainAllModelsButton
        function MT_DNN_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_DNN_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end
            
            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainDNNs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_DNN_OptimizeSelectionButton
        function MT_DNN_OptimizeSelectionButtonPushed(app, event)
            models = app.MT_DNN_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'DNN_selection', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_DNN_OptimizeAllButton
        function MT_DNN_OptimizeAllButtonPushed(app, event)
            models = app.MT_DNN_ModelsListBox.ItemsData;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'DNN_all', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_CML_AddModelButton
        function MT_CML_AddModelButtonPushed(app, event)
            app.popup_ModelSelection_CML = ModelSelection_CML(app);
        end

        % Button pushed function: MT_CML_AddAllModelsButton
        function MT_CML_AddAllModelsButtonPushed(app, event)
            app.removeDetection(app.MT_CML_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.ItemsData = [];

            % Load all models with their configuration
            fid = fopen('tsad_platform_config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_CML = models_All.CML_Models;
            
            if ~isempty(models_CML)
                for i = 1:length(models_CML)
                    app.addModelToList(models_CML(i).options);
                end
            end

            app.toggleTrainButtons('CML', true);
        end

        % Button pushed function: MT_CML_ClearModelsButton
        function MT_CML_ClearModelsButtonPushed(app, event)
            app.removeDetection(app.MT_CML_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.ItemsData = [];

            app.toggleTrainButtons('CML', false);
        end

        % Menu selected function: MT_CML_ShowModelParametersMenu
        function MT_CML_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_CML_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_CML_DeleteMenu
        function MT_CML_DeleteMenuSelected(app, event)
            selectedItems = app.MT_CML_ModelsListBox.Value;
            
            app.removeDetection(selectedItems);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_CML_ModelsListBox.Items);
                app.MT_CML_ModelsListBox.Items(idx) = [];
                app.MT_CML_ModelsListBox.ItemsData(idx) = [];
            end

            if isempty(app.MT_CML_ModelsListBox.Items)
                app.toggleTrainButtons('CML', false)
            end
        end

        % Button pushed function: MT_CML_TrainSelectedModelsButton
        function MT_CML_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_CML_ModelsListBox.Value;

            if isempty(models)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainCMLs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_CML_TrainAllModelsButton
        function MT_CML_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_CML_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error', Icon='error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainCMLs(models);
        
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_CML_OptimizeSelectionButton
        function MT_CML_OptimizeSelectionButtonPushed(app, event)
            models = app.MT_CML_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'CML_selection', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_CML_OptimizeAllButton
        function MT_CML_OptimizeAllButtonPushed(app, event)
            models = app.MT_CML_ModelsListBox.ItemsData;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'CML_all', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_S_AddModelButton
        function MT_S_AddModelButtonPushed(app, event)
            app.popup_ModelSelection_S = ModelSelection_S(app);
        end

        % Button pushed function: MT_S_AddAllModelsButton
        function MT_S_AddAllModelsButtonPushed(app, event)
            app.removeDetection(app.MT_S_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.ItemsData = [];         

            % Load all models with their configuration
            fid = fopen('tsad_platform_config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_S = models_All.S_Models;
            
            if ~isempty(models_S)
                for i = 1:length(models_S)
                    app.addModelToList(models_S(i).options);
                end
            end

            app.toggleTrainButtons('S', true);
        end

        % Button pushed function: MT_S_ClearModelsButton
        function MT_S_ClearModelsButtonPushed(app, event)
            app.removeDetection(app.MT_DNN_ModelsListBox.ItemsData);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.ItemsData = [];

            app.toggleTrainButtons('S', false);
        end

        % Menu selected function: MT_S_ShowModelParametersMenu
        function MT_S_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_S_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_S_DeleteMenu
        function MT_S_DeleteMenuSelected(app, event)
            selectedItems = app.MT_S_ModelsListBox.Value;
            
            app.removeDetection(selectedItems);
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            app.updateCompTimePlot();

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_S_ModelsListBox.Items);
                app.MT_S_ModelsListBox.Items(idx) = [];
                app.MT_S_ModelsListBox.ItemsData(idx) = [];
            end

            if isempty(app.MT_S_ModelsListBox.Items)
                app.toggleTrainButtons('S', false);
            end
        end

        % Button pushed function: MT_S_TrainSelectedModelsButton
        function MT_S_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_S_ModelsListBox.Value;

            if isempty(models)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainSs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_S_TrainAllModelsButton
        function MT_S_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(models)
                    if models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainSs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_S_OptimizeSelectionButton
        function MT_S_OptimizeSelectionButtonPushed(app, event)
            models = app.MT_S_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'S_selection', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_S_OptimizeAllButton
        function MT_S_OptimizeAllButtonPushed(app, event)
            models = app.MT_S_ModelsListBox.ItemsData;
            if isempty(models)
                uialert(app.UIFigure, 'No models found for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                if ~isempty(app.popup_OptimizeAll)
                    app.popup_OptimizeAll.delete();
                end
                app.popup_OptimizeAll = OptimizeAll(app, 'S_all', app.settings_thresholds);
                return;
            end
            if ~isempty(app.popup_OptimizeParameters)
                app.popup_OptimizeParameters.delete();
            end
            app.popup_OptimizeParameters = OptimizeParameters(app, models(1).options, app.settings_thresholds);
        end

        % Button pushed function: MT_TrainAllButton
        function MT_TrainAllButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            DNN_models = app.MT_DNN_ModelsListBox.ItemsData;
            CML_models = app.MT_CML_ModelsListBox.ItemsData;
            S_models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(DNN_models) && isempty(CML_models) && isempty(S_models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.dataTrain)
                for i = 1:numel(DNN_models)
                    if DNN_models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
                for i = 1:numel(CML_models)
                    if CML_models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
                for i = 1:numel(S_models)
                    if S_models(i).options.requiresPriorTraining
                        uialert(app.UIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", "Training Error", Icon='error');
                        return;
                    end
                end
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            if ~isempty(DNN_models)
                app.trainDNNs(DNN_models);
            end
            if ~isempty(CML_models)
                app.trainCMLs(CML_models);
            end
            if ~isempty(S_models)
                app.trainSs(S_models);
            end
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_OptimizeAllButton
        function MT_OptimizeAllButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for optimization!', 'Training Error', Icon='error');
                return;
            end
            
            DNN_models = app.MT_DNN_ModelsListBox.ItemsData;
            CML_models = app.MT_CML_ModelsListBox.ItemsData;
            S_models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(DNN_models) && isempty(CML_models) && isempty(S_models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end
            
            if ~isempty(app.popup_OptimizeAll)
                app.popup_OptimizeAll.delete();
            end
            app.popup_OptimizeAll = OptimizeAll(app, 'all', app.settings_thresholds);
        end

        % Button pushed function: MT_PreviousButton
        function MT_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Button pushed function: MT_NextButton
        function MT_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;
        end

        % Button pushed function: DE_RunDetectionForSelectedButton
        function DE_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', ...
                            'Detection Error', Icon='error');
                return;
            end
            
            if isempty(app.trainedModels)
                uialert(app.UIFigure, "No trained models found. Train them first on the training panel.", 'Model Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Detection',...
                Indeterminate='on');
            drawnow;

            models = app.DE_TrainedModelsListBox.Value;
            app.runDetectionFor(models);
                    
            app.info_detectionRun = true;
            
            close(d);
            uialert(app.UIFigure, 'Detection finished successfully!', ...
                            'Detection Success', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DE_RunDetectionForAllButton
        function DE_RunDetectionForAllButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', ...
                            'Detection Error', Icon='error');
                return;
            end
            
            if isempty(app.trainedModels)
                uialert(app.UIFigure, "No trained models found. Train them first on the training panel.", 'Model Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Detection',...
                Indeterminate='on');
            drawnow;
            
            models = app.DE_TrainedModelsListBox.ItemsData;
            app.runDetectionFor(models);
            
            app.info_detectionRun = true;
            
            close(d);
            uialert(app.UIFigure, 'Detection finished successfully!', ...
                            'Detection Success', Icon='success');
            app.endTask(oldPointer);
        end

        % Value changed function: DE_AnomalyPaddingSlider
        function DE_AnomalyPaddingSliderValueChanged(app, event)
            app.settings_dynamicThreshold.anomalyPadding = app.DE_AnomalyPaddingSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_options, app.currentDetection_anomalyScores, app.currentDetection_YTest, app.currentDetection_labels, ...
                    "dynamic", app.currentDetection_id, true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_WindowSizeSlider
        function DE_WindowSizeSliderValueChanged(app, event)
            app.settings_dynamicThreshold.windowSize = app.DE_WindowSizeSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end
            
            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_options, app.currentDetection_anomalyScores, app.currentDetection_YTest, app.currentDetection_labels, ...
                    "dynamic", app.currentDetection_id, true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_ZRangeSlider
        function DE_ZRangeSliderValueChanged(app, event)
            app.settings_dynamicThreshold.zRange = app.DE_ZRangeSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_options, app.currentDetection_anomalyScores, app.currentDetection_YTest, app.currentDetection_labels, ...
                    "dynamic", app.currentDetection_id, true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Button pushed function: DE_ImportTrainedModelsButton
        function DE_ImportTrainedModelsButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uigetfile('*.mat', ...
               'Select a configuration file', pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                
                trainedModels_tmp = load(filePath).trainedModels;

                fields = fieldnames(trainedModels_tmp);
                for i = 1:length(fields)
                    [label_tmp, id_tmp, instanceNumber_tmp] = app.getLabelForModel(trainedModels_tmp.(fields{i}).options.model, ...
                                                                                    trainedModels_tmp.(fields{i}).options.type);
                    app.addModelToList(trainedModels_tmp.(fields{i}).options);
                    
                    modifiedTrainedModel = trainedModels_tmp.(fields{i});
                    modifiedTrainedModel.options.label = label_tmp;
                    modifiedTrainedModel.options.id = id_tmp;
                    modifiedTrainedModel.options.instanceNumber = instanceNumber_tmp;

                    trainedModels_tmp = rmfield(trainedModels_tmp, fields{i});
                    modifiedTrainedModels.(id_tmp) = modifiedTrainedModel;
                end
                
                if exist("modifiedTrainedModels", "var")
                    fields = fieldnames(modifiedTrainedModels);
                    for i = 1:length(fields)
                        app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                                      NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                        app.trainedModels.(fields{i}) = modifiedTrainedModels.(fields{i});
                    end
    
                    app.showTrainedModels();
                    app.showScoresInTable();
                    app.sortTrainedModels();
                    app.updateCompTimePlot();
                end
            end
        end

        % Value changed function: DE_ThresholdDropDown
        function DE_ThresholdDropDownValueChanged(app, event)
            selectedItem = getThresholdId(app.DE_ThresholdDropDown.Value);

            app.togglePlotsForThresholding(selectedItem);
            app.currentDetection_threshold = selectedItem;
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            staticThreshold = app.trainedModels.(app.currentDetection_id).staticThreshold;

            if ~isempty(staticThreshold) && isfield(staticThreshold, selectedItem)
                selectedThreshold = staticThreshold.(selectedItem);
            else
                selectedThreshold = selectedItem;
            end

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_options, app.currentDetection_anomalyScores, app.currentDetection_YTest, app.currentDetection_labels, ...
                    selectedThreshold, app.currentDetection_id, true);
            end


            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_SortByDropDown
        function DE_SortByDropDownValueChanged(app, event)
            oldPointer = app.beginTask();
            
            app.showTrainedModels();
            app.sortTrainedModels();

            app.endTask(oldPointer);
        end

        % Button pushed function: DE_ExportTableButton
        function DE_ExportTableButtonPushed(app, event)
            table = app.DE_FullDetectionUITable.Data;
            table.Properties.VariableNames = app.DE_FullDetectionUITable.ColumnName;
            
            variableNames = table.Properties.VariableNames;
            if length(variableNames) < 2
                return;
            end


            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.csv', 'File Selection', sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end

            fileName_Results = fullfile(path, file);

            writetable(table, fileName_Results);

            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end

        % Button pushed function: DE_SaveSelectedModelsButton
        function DE_SaveSelectedModelsButtonPushed(app, event)
            selectedModels = app.DE_TrainedModelsListBox.Value;
            if isempty(selectedModels)
                uialert(app.UIFigure, 'No models selected for export!', 'Export Error');
                return;
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.mat', 'File Selection', sprintf('models_%s.mat', datestr(now,'mm-dd-yyyy_HH-MM')));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end
            
            for i = 1:numel(selectedModels)
                trainedModels.(selectedModels(i).id) = app.trainedModels.(selectedModels(i).id);
            end

            fileName = fullfile(path, file);
            assignin('base', 'trainedModels', trainedModels);
            save(fileName, 'trainedModels');
            
            uialert(app.UIFigure, sprintf('Saved models to %s', fileName), 'Models Saved', Icon='success');
        end

        % Button pushed function: DE_CompTimeMetricSelectionButton
        function DE_CompTimeMetricSelectionButtonPushed(app, event)
            app.popup_CompTimeMetricSelection = CompTimeMetricSelection(app, app.compTimeMetrics);
        end

        % Button pushed function: SD_ImportTrainedModelsButton
        function SD_ImportTrainedModelsButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uigetfile('*.mat', ...
               'Select a configuration file', pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                
                try
                    trainedModels_tmp = load(filePath).trainedModels;
                catch
                    uialert(app.UIFigure, "Trained models file is in the wrong format!", "Import error", Icon="error");
                    return;
                end

                fields = fieldnames(trainedModels_tmp);
                for i = 1:length(fields)
                    [label_tmp, id_tmp, instanceNumber_tmp] = app.getLabelForModel(trainedModels_tmp.(fields{i}).options.model, ...
                                                                                    trainedModels_tmp.(fields{i}).options.type);
                    app.addModelToList(trainedModels_tmp.(fields{i}).options);
                    
                    modifiedTrainedModel = trainedModels_tmp.(fields{i});
                    modifiedTrainedModel.options.label = label_tmp;
                    modifiedTrainedModel.options.id = id_tmp;
                    modifiedTrainedModel.options.instanceNumber = instanceNumber_tmp;

                    trainedModels_tmp = rmfield(trainedModels_tmp, fields{i});
                    modifiedTrainedModels.(id_tmp) = modifiedTrainedModel;
                end
                
                if exist("modifiedTrainedModels", "var")
                    fields = fieldnames(modifiedTrainedModels);
                    for i = 1:length(fields)
                        app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                                      NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                        app.trainedModels.(fields{i}) = modifiedTrainedModels.(fields{i});
                    end
    
                    app.showTrainedModels();
                    app.showScoresInTable();
                    app.sortTrainedModels();
                    app.updateCompTimePlot();
                end
            end
        end

        % Button pushed function: SD_SaveSelectedModelsButton
        function SD_SaveSelectedModelsButtonPushed(app, event)
            selectedModels = app.SD_TrainedModelsListBox.Value;
            if isempty(selectedModels)
                uialert(app.UIFigure, 'No models selected for export!', 'Export Error');
                return;
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.mat', 'File Selection', sprintf('models_%s.mat', datestr(now,'mm-dd-yyyy_HH-MM')));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end
            
            for i = 1:numel(selectedModels)
                trainedModels.(selectedModels(i).id) = app.trainedModels.(selectedModels(i).id);
            end

            fileName = fullfile(path, file);
            assignin('base', 'trainedModels', trainedModels);
            save(fileName, 'trainedModels');
            
            uialert(app.UIFigure, sprintf('Saved models to %s', fileName), 'Models Saved', Icon='success');
        end

        % Button pushed function: SD_RunDetectionForSelectedButton
        function SD_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', 'Simulink Detection Error', Icon='error');
                return;
            end

            if isempty(app.trainedModels)
                uialert(app.UIFigure, "No trained models found. Train them first on the training panel.", 'Model Error', Icon='error');
                return;
            end
            
            model = app.SD_TrainedModelsListBox.Value;

            if numel(model) > 1
                uialert(app.UIFigure, 'Please select just one model for Simulink detection', 'Simulink Detection Error', Icon='error');
                return;
            elseif isempty(model)
                uialert(app.UIFigure, 'No model selected for Simulink detection', 'Simulink Detection Error', Icon='error');
                return;
            end

            modelId = model.id;
            mdl = app.trainedModels.(modelId);
            staticThreshold = mdl.staticThreshold;

            if ~isempty(staticThreshold) && isfield(staticThreshold, app.currentDetection_threshold)
                selectedThreshold = staticThreshold.(app.currentDetection_threshold);
            else
                uialert(app.UIFigure, 'Threshold not available. Some thresholds require the use of an anomalous validation set to be computed (see Preprocessing panel).', 'Simulink Detection Error', Icon='error');
                return;
            end


            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Detection on Simulink',...
                Indeterminate='on');
            drawnow;

            wSize = mdl.options.hyperparameters.data.windowSize.value;
            mdlType = mdl.options.modelType;
            netPath = app.saveModelForSim(mdl.Mdl, modelId);
            currentMdlLabel = app.trainedModels.(modelId).options.label;
            app.SD_DetectionShownForLabel.Text = sprintf('Detection shown for: %s', currentMdlLabel);
            if strcmp(mdlType, 'predictive')
                simModel = "PredictiveSim";
                load_system(simModel)
                path = strcat(simModel, "/Prediction model");
                set_param(path,'BufferLength',num2str(wSize))
                set_param(path,'NetworkFilePath',netPath)
            else
                simModel = "AutoEncoderPredictSim";
                load_system(simModel)
                path = strcat(simModel, "/Reconstruction model");
                set_param(path,'BufferLength',num2str(wSize))
                set_param(path,'NetworkFilePath',netPath)
            end
            if app.SD_OpenmodelCheckBox.Value == 1
                open(simModel)
            end
            selectedItem = app.SD_FaultyDataSelectionDropDown.Value;
            [~, idxData] = ismember(selectedItem, app.SD_FaultyDataSelectionDropDown.Items);
            testingData = app.dataTest(idxData, 1);
            testingLabels = app.labelsTest(idxData, 1);
            duration = length(testingLabels{1});
            assignin("base", "data", timeseries(testingData{1}))
            assignin("base", "labels", timeseries(testingLabels{1}))
            assignin("base", "threshold", selectedThreshold)


            % Plot true data and found anomalies
            simulated = sim(simModel, 'StopTime', num2str(duration));
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,1))
            hold(app.SD_FoundAnomaliesUIAxes, "on")
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,2), 'r')
            hold(app.SD_FoundAnomaliesUIAxes, "off")
            legend(app.SD_FoundAnomaliesUIAxes, ["Data", "Found anomalies"])

            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,1))
            hold(app.SD_TrueAnomaliesUIAxes, "on")
            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,2), 'r')
            hold(app.SD_TrueAnomaliesUIAxes, "off")
            legend(app.SD_TrueAnomaliesUIAxes, ["Data", "True anomalies"])

            app.SD_FoundAnomaliesUIAxes.Visible = "on";
            app.SD_TrueAnomaliesUIAxes.Visible = "on";


            close(d);
            uialert(app.UIFigure, 'Detection finished successfully!', ...
                'Detection Success', Icon='success');
            app.endTask(oldPointer);
        end

        % Value changed function: DS_SelectedFileTestSwitchDropDown
        function DS_SelectedFileTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData('test-switch');
        end

        % Value changed function: DS_SelectedChannelTestSwitchDropDown
        function DS_SelectedChannelTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData('test-switch');
        end

        % Callback function
        function DS_SplitTestSetCheckBoxValueChanged(app, event)
            if app.DS_SplitTestSetCheckBox.Value
                app.DS_RatioTestValLabel.Enable = 'on';
                app.DS_RatioTestValEditField.Enable = 'on';
            else
                app.DS_RatioTestValLabel.Enable = 'off';
                app.DS_RatioTestValEditField.Enable = 'off';
            end
        end

        % Button pushed function: DS_AutoLabelDatasetButton
        function DS_AutoLabelDatasetButtonPushed(app, event)
            if ~app.P_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.UIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", 'Data Error', Icon='error');
                return;
            end

            if isempty(app.trainedModels)
                uialert(app.UIFigure, "No trained models found. Train them first on the training panel.", 'Model Error', Icon='error');
                return;
            end 

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Labeling Dataset',...
                Indeterminate='on');
            drawnow;

            cmpMetric = app.DS_ScoreDropDown.Value;
            threshold = getThresholdId(app.DS_ThresholdDropDown.Value);
            
            app.ds_bestModels = findBestModels(app.trainedModels, app.dataTest, app.labelsTest, app.filesTest, threshold, app.settings_dynamicThreshold, cmpMetric);
            
            app.toggleLamp(app.DS_AutoLabelLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully labeled dataset.', 'Labeling Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DS_TrainClassifierButton
        function DS_TrainClassifierButtonPushed(app, event)
            if ~app.P_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.UIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", 'Data Error', Icon='error');
                return;
            end
            
            oldPointer = app.beginTask();

            d = uiprogressdlg(app.UIFigure, Title='Training classifier',...
                Indeterminate='on');
            drawnow;

            app.ds_classifier = trainDynamicSwitchClassifier(app.dataTest, app.ds_bestModels, app.filesTest);
            
            app.toggleLamp(app.DS_TrainLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully trained classifier.', 'Training Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DS_RunEvaluationsButton
        function DS_RunEvaluationsButtonPushed(app, event)
            if ~app.P_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.UIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", 'Data Error', Icon='error');
                return;
            end       

            if isempty(app.trainedModels)
                uialert(app.UIFigure, "No trained models found. Train them first on the training panel.", 'Model Error', Icon='error');
                return;
            end 
            
            if isempty(app.ds_classifier)
                uialert(app.UIFigure, "No classifier trained!", 'Classifier Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Evaluating Dynamic Switch',...
                Indeterminate='on');
            drawnow;
            
            threshold = getThresholdId(app.DS_ThresholdDropDown.Value);

            finalTable = evaluateDynamicSwitch(app.ds_classifier, app.trainedModels, app.dataTestSwitch, app.labelsTestSwitch, threshold, app.settings_dynamicThreshold);

            app.DS_ResultsUITable.Data = finalTable;
            app.DS_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.DS_ResultsUITable);
            app.highlightTableMaxima(app.DS_ResultsUITable, 'row-wise');
            
            app.toggleLamp(app.DS_EvaluationLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully finished evaluation.', 'Evaluation Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DS_ExportButton
        function DS_ExportButtonPushed(app, event)
            table = app.DS_ResultsUITable.Data;
            table.Properties.VariableNames = app.DS_ResultsUITable.ColumnName;

            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uiputfile('*.csv', 'File Selection', sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            delete(f);
            if isequal(path, 0)
                uialert(app.UIFigure, "No valid path selected.", 'Export Error');
                return;
            end

            fileName_Results = fullfile(path, file);

            writetable(table, fileName_Results);

            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end

        % Button pushed function: AR_BrowseButton
        function AR_BrowseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_DatasetEditField.Value = path;
            end
        end

        % Callback function
        function AR_AutoBrowseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_AutoDatasetEditField.Value = path;
            end
        end

        % Value changed function: AR_AugmentedDataCheckBock
        function AR_AugmentedDataCheckBockValueChanged(app, event)
            augmentation = app.AR_AugmentedDataCheckBock.Value;
            if augmentation
                app.AR_Choice0fAugmentationListBox.Visible='on';
                app.AR_intensitySlider.Visible='on';
                app.AR_ChoiceofaugmentationListBoxLabel.Visible='on';
                app.AR_intensitySliderLabel.Visible='on';
                app.AR_intensitySlider.Limits = [0, 1];
                app.AR_intensitySliderLabel.Text = 'Standard deviation';
            else
                app.AR_Choice0fAugmentationListBox.Visible='off';
                app.AR_intensitySlider.Visible='off';
                app.AR_ChoiceofaugmentationListBoxLabel.Visible='off';
                app.AR_intensitySliderLabel.Visible='off';
            end


        end

        % Value changed function: AR_UseAnomalousValidationSetCheckBox
        function AR_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.AR_UseAnomalousValidationSetCheckBox.Value
                app.AR_RatioTestValLabel.Enable = 'on';
                app.AR_RatioTestValEditField.Enable = 'on';
            else
                app.AR_RatioTestValLabel.Enable = 'off';
                app.AR_RatioTestValEditField.Enable = 'off';
                
            end
        end

        % Value changed function: AR_Choice0fAugmentationListBox
        function AR_Choice0fAugmentationListBoxValueChanged(app, event)
            value = app.AR_Choice0fAugmentationListBox.Value;
            

            %changing the visual of the slider depending on the transformation chosen

            switch value
                case 'none'
                    app.AR_intensitySlider.Visible='off';
                    app.AR_intensitySliderLabel.Visible='off';

                case 'white noise'
                    app.AR_intensitySlider.Limits = [0, 1];
                    app.AR_intensitySliderLabel.Text = 'Standard deviation';
                    app.AR_intensitySlider.Visible='on';
                    app.AR_intensitySliderLabel.Visible='on';

                case 'random walk'
                    app.AR_intensitySlider.Limits = [0, 1];
                    app.AR_intensitySliderLabel.Text = 'Standard deviation';
                    app.AR_intensitySlider.Visible='on';
                    app.AR_intensitySliderLabel.Visible='on';
                
                case 'global shift'
                    app.AR_intensitySlider.Limits = [-100, 100];
                    app.AR_intensitySliderLabel.Text = 'Shift of the % of the max value';
                    app.AR_intensitySlider.Visible='on';
                    app.AR_intensitySliderLabel.Visible='on';

                case 'attenuate extremum'
                    app.AR_intensitySlider.Limits = [0, 100];
                    app.AR_intensitySliderLabel.Text = 'Multiply each value by the %';
                    app.AR_intensitySlider.Visible='on';
                    app.AR_intensitySliderLabel.Visible='on';
                    
            end
        end

        % Button pushed function: AR_RunEvaluationButton
        function AR_RunEvaluationButtonPushed(app, event)
            datasetPath = app.AR_DatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                return;
            end

            % Load all models as added on the Training tab
            models.models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
            models.models_CML = app.MT_CML_ModelsListBox.ItemsData;
            models.models_S = app.MT_S_ModelsListBox.ItemsData;

            if isempty(models.models_DNN) && isempty(models.models_CML) && isempty(models.models_S)
                uialert(app.UIFigure, "No models found for evaluation. Add them on the Training panel.", 'Model Error', Icon='error');
                return;
            end 
 
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Evaluating Dataset',...
                Indeterminate='on');
            drawnow;

            useFraction = app.AR_UseFractionEditField.Value;
            if app.AR_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.AR_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end
            preprocMethod = app.AR_PreprocessingMethodDropDown.Value;
            trainParallel = app.AR_ParallelCheckBox.Value;
            augmentationChoice=app.AR_Choice0fAugmentationListBox.Value;
            intensity=app.AR_intensitySlider.Value;
            augmented=app.AR_AugmentedDataCheckBock.Value;
            augmentedTraining= app.AR_AugmentedTrainingCheckBock.Value;

            if app.AR_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            if  (augmented)
                noAugmentedTable = evaluateAllForDataset(datasetPath, models, ...
                                                useFraction, preprocMethod, ...
                                                ratioTestVal, app.settings_thresholds, ...
                                                app.settings_dynamicThreshold, ...
                                                trainingPlots, trainParallel);

                augmentedTable =GT_evaluateAllForDataset(datasetPath, models, ...
                                                useFraction, preprocMethod, ...
                                                ratioTestVal, app.settings_thresholds, ...
                                                app.settings_dynamicThreshold, ...
                                                trainingPlots, trainParallel,augmentationChoice,intensity,augmentedTraining);

                finalTable = join (noAugmentedTable,augmentedTable,'Key', 'Metric');


            else
                finalTable = evaluateAllForDataset(datasetPath, models, ...
                                                useFraction, preprocMethod, ...
                                                ratioTestVal, app.settings_thresholds, ...
                                                app.settings_dynamicThreshold, ...
                                                trainingPlots, trainParallel);

            
            end

            app.AR_ResultsUITable.Data = finalTable;
            app.AR_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.AR_ResultsUITable);
            app.highlightTableMaxima(app.AR_ResultsUITable, 'row-wise');
            
            close(d);
            uialert(app.UIFigure, 'Successfully finished evaluating all models on selected dataset. Results saved to Dataset_Sweep_Results folder in the current Matlab folder', 'Auto Run Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: AR_VisualizeDataButton
        function AR_VisualizeDataButtonPushed(app, event)
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Loading Dataset',...
                Indeterminate='on');
            drawnow;
            app.popup_VisualizationData= VisualizationData(app.AR_DatasetEditField.Value,app.AR_Choice0fAugmentationListBox.Value,app.AR_intensitySlider.Value,app.AR_AugmentedTrainingCheckBock.Value);
            close(d);
            app.endTask(oldPointer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ThresholdSelectionMenu = uimenu(app.SettingsMenu);
            app.ThresholdSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ThresholdSelectionMenuSelected, true);
            app.ThresholdSelectionMenu.Text = 'Threshold Selection';
            app.DynamicThresholdMenu = uimenu(app.SettingsMenu);
            app.DynamicThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @DynamicThresholdMenuSelected, true);
            app.DynamicThresholdMenu.Text = 'Dynamic Threshold';
            app.OptimizationMenu = uimenu(app.SettingsMenu);
            app.OptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizationMenuSelected, true);
            app.OptimizationMenu.Text = 'Optimization';
            app.MainPanel = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.MainPanel);
            app.DataTab.Title = '1.1 Data';
            app.DataPanel = uipanel(app.DataTab);
            app.D_RawTrainingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (raw)')
            xlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Z')
            app.D_RawTestingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (raw)')
            xlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Z')
            app.D_NextButton = uibutton(app.DataPanel, 'push');
            app.D_NextButton.ButtonPushedFcn = createCallbackFcn(app, @D_NextButtonPushed, true);
            app.D_NextButton.Text = 'Next';
            app.SelectedChannelLabel = uilabel(app.DataPanel);
            app.SelectedChannelLabel.Text = 'Selected Channel:';
            app.D_SelectedChannelTrainDropDown = uidropdown(app.DataPanel);
            app.D_SelectedChannelTrainDropDown.Items = {};
            app.D_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTrainDropDownValueChanged, true);
            app.D_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel = uilabel(app.DataPanel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.D_SelectedFileTrainDropDown = uidropdown(app.DataPanel);
            app.D_SelectedFileTrainDropDown.Items = {};
            app.D_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTrainDropDownValueChanged, true);
            app.D_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_2 = uilabel(app.DataPanel);
            app.SelectedChannelLabel_2.Text = 'Selected Channel:';
            app.D_SelectedChannelTestDropDown = uidropdown(app.DataPanel);
            app.D_SelectedChannelTestDropDown.Items = {};
            app.D_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTestDropDownValueChanged, true);
            app.D_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_2 = uilabel(app.DataPanel);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.D_SelectedFileTestDropDown = uidropdown(app.DataPanel);
            app.D_SelectedFileTestDropDown.Items = {};
            app.D_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTestDropDownValueChanged, true);
            app.D_SelectedFileTestDropDown.Value = {};
            app.DatasetSelectionPanel = uipanel(app.DataPanel);
            app.DatasetSelectionPanel.Title = 'Dataset Selection';
            app.D_LoadDataButton = uibutton(app.DatasetSelectionPanel, 'push');
            app.D_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @D_LoadDataButtonPushed, true);
            app.D_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.D_LoadDataButton.Text = 'Load Data';
            app.D_BrowseCustomDatasetButton = uibutton(app.DatasetSelectionPanel, 'push');
            app.D_BrowseCustomDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @D_BrowseCustomDatasetButtonPushed, true);
            app.D_BrowseCustomDatasetButton.Text = 'Browse...';
            app.D_ImportCustomDatasetLabel = uilabel(app.DatasetSelectionPanel);
            app.D_ImportCustomDatasetLabel.Text = 'Dataset Folder:';
            app.D_ImportCustomDatasetEditField = uieditfield(app.DatasetSelectionPanel, 'text');
            app.D_MaximumLabel = uilabel(app.DatasetSelectionPanel);
            app.D_MaximumLabel.Text = 'Maximum: -';
            app.D_MinimumLabel = uilabel(app.DatasetSelectionPanel);
            app.D_MinimumLabel.Text = 'Minimum: -';
            app.D_MeanLabel = uilabel(app.DatasetSelectionPanel);
            app.D_MeanLabel.Text = 'Mean: -';
            app.D_StandardDeviationLabel = uilabel(app.DatasetSelectionPanel);
            app.D_StandardDeviationLabel.Text = 'Standard Deviation: -';
            app.D_DatasetAttributesLabel = uilabel(app.DatasetSelectionPanel);
            app.D_DatasetAttributesLabel.Text = 'Dataset Attributes:';
            app.PreprocessingTab = uitab(app.MainPanel);
            app.PreprocessingTab.Title = '1.2 Preprocessing';
            app.PreprocessingPanel = uipanel(app.PreprocessingTab);
            app.P_PreprocessedTrainingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Z')
            app.P_PreprocessedTestingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Z')
            app.P_PreprocessedTestValDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTestValDatasetPreviewUIAxes, 'Preview: Testing Validation Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTestValDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTestValDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTestValDatasetPreviewUIAxes, 'Z')
            app.P_NextButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_NextButton.ButtonPushedFcn = createCallbackFcn(app, @P_NextButtonPushed, true);
            app.P_NextButton.Text = 'Next';
            app.P_PreviousButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @P_PreviousButtonPushed, true);
            app.P_PreviousButton.Text = 'Previous';
            app.P_MethodButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.P_MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @P_MethodButtonGroupSelectionChanged, true);
            app.P_MethodButtonGroup.Title = 'Preprocessing Method';
            app.P_RawDataButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RawDataButton.Text = 'Raw Data';
            app.P_StandardizeButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_StandardizeButton.Text = 'Standardize';
            app.P_RescaleButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RescaleButton.Text = 'Rescale [0, 1] (recommended)';
            app.P_RescaleButton.Value = true;
            app.P_RatioTestValEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.P_RatioTestValEditField.Limits = [0.01 0.99];
            app.P_RatioTestValEditField.ValueChangedFcn = createCallbackFcn(app, @P_RatioTestValEditFieldValueChanged, true);
            app.P_RatioTestValEditField.Enable = 'off';
            app.P_RatioTestValEditField.Value = 0.2;
            app.P_UseAnomalousValidationSetCheckBox = uicheckbox(app.PreprocessingPanel);
            app.P_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @P_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.P_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.SelectedChannelLabel_3 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_3.Text = 'Selected Channel:';
            app.P_SelectedChannelTrainDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelTrainDropDown.Items = {};
            app.P_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelTrainDropDownValueChanged, true);
            app.P_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel_3 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_3.Text = 'Selected File:';
            app.P_SelectedFileTrainDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileTrainDropDown.Items = {};
            app.P_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileTrainDropDownValueChanged, true);
            app.P_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_4 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_4.Text = 'Selected Channel:';
            app.P_SelectedChannelTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelTestDropDown.Items = {};
            app.P_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelTestDropDownValueChanged, true);
            app.P_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_4 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_4.Text = 'Selected File:';
            app.P_SelectedFileTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileTestDropDown.Items = {};
            app.P_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileTestDropDownValueChanged, true);
            app.P_SelectedFileTestDropDown.Value = {};
            app.SelectedChannelLabel_5 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_5.Text = 'Selected Channel:';
            app.P_SelectedChannelTestValDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelTestValDropDown.Items = {};
            app.P_SelectedChannelTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelTestValDropDownValueChanged, true);
            app.P_SelectedChannelTestValDropDown.Value = {};
            app.SelectedFileLabel_5 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_5.Text = 'Selected File:';
            app.P_SelectedFileTestValDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileTestValDropDown.Items = {};
            app.P_SelectedFileTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileTestValDropDownValueChanged, true);
            app.P_SelectedFileTestValDropDown.Value = {};
            app.P_RatioTestValLabel = uilabel(app.PreprocessingPanel);
            app.P_RatioTestValLabel.Enable = 'off';
            app.P_RatioTestValLabel.Text = 'Ratio:';
            app.P_SplitTestSetForDynamicSwitchCheckBox = uicheckbox(app.PreprocessingPanel);
            app.P_SplitTestSetForDynamicSwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @P_SplitTestSetForDynamicSwitchCheckBoxValueChanged, true);
            app.P_SplitTestSetForDynamicSwitchCheckBox.Enable = 'off';
            app.P_SplitTestSetForDynamicSwitchCheckBox.Text = 'Split Test Set for Dynamic Switch';
            app.P_RatioTestSwitchEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.P_RatioTestSwitchEditField.Limits = [0.01 0.99];
            app.P_RatioTestSwitchEditField.ValueChangedFcn = createCallbackFcn(app, @P_RatioTestSwitchEditFieldValueChanged, true);
            app.P_RatioTestSwitchEditField.Enable = 'off';
            app.P_RatioTestSwitchEditField.Value = 0.5;
            app.P_RatioTestSwitchLabel = uilabel(app.PreprocessingPanel);
            app.P_RatioTestSwitchLabel.Enable = 'off';
            app.P_RatioTestSwitchLabel.Text = 'Ratio:';
            app.ChoiceofaugmentationListBoxLabel = uilabel(app.PreprocessingPanel);
            app.ChoiceofaugmentationListBoxLabel.Text = 'Choice of augmentation';
            app.P_Choice0fAugmentationListBox = uilistbox(app.PreprocessingPanel);
            app.P_Choice0fAugmentationListBox.Items = {'none', 'white noise', 'random walk', 'global shift', 'attenuate extremum'};
            app.P_Choice0fAugmentationListBox.ValueChangedFcn = createCallbackFcn(app, @P_Choice0fAugmentationListBoxValueChanged, true);
            app.P_Choice0fAugmentationListBox.Value = 'none';
            app.intensitySliderLabel = uilabel(app.PreprocessingPanel);
            app.intensitySliderLabel.Visible = 'off';
            app.intensitySliderLabel.Text = 'intensity';
            app.P_intensitySlider = uislider(app.PreprocessingPanel);
            app.P_intensitySlider.ValueChangedFcn = createCallbackFcn(app, @P_intensitySliderValueChanged, true);
            app.P_intensitySlider.Visible = 'off';
            app.P_AugmentedTrainingCheckBock = uicheckbox(app.PreprocessingPanel);
            app.P_AugmentedTrainingCheckBock.ValueChangedFcn = createCallbackFcn(app, @P_AugmentedTrainingCheckBockValueChanged, true);
            app.P_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.ModelTrainingTab = uitab(app.MainPanel);
            app.ModelTrainingTab.Title = '1.3 Training';
            app.ModelTrainingPanel = uipanel(app.ModelTrainingTab);
            app.MT_NextButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_NextButton.ButtonPushedFcn = createCallbackFcn(app, @MT_NextButtonPushed, true);
            app.MT_NextButton.Text = 'Next';
            app.MT_PreviousButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @MT_PreviousButtonPushed, true);
            app.MT_PreviousButton.Text = 'Previous';
            app.MT_DeepNeuralNetworksPanel = uipanel(app.ModelTrainingPanel);
            app.MT_DeepNeuralNetworksPanel.Title = 'Deep Neural Networks';
            app.MT_DNN_ModelsListBox = uilistbox(app.MT_DeepNeuralNetworksPanel);
            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.Multiselect = 'on';
            app.MT_DNN_ModelsListBox.Value = {};
            app.MT_DNN_AddModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_AddModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_AddModelsButtonPushed, true);
            app.MT_DNN_AddModelsButton.Text = 'Add';
            app.MT_DNN_ClearModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_ClearModelsButtonPushed, true);
            app.MT_DNN_ClearModelsButton.Text = 'Clear All';
            app.MT_DNN_TrainSelectedModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_TrainSelectedModelsButtonPushed, true);
            app.MT_DNN_TrainSelectedModelsButton.Enable = 'off';
            app.MT_DNN_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_DNN_TrainAllModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_TrainAllModelsButtonPushed, true);
            app.MT_DNN_TrainAllModelsButton.Enable = 'off';
            app.MT_DNN_TrainAllModelsButton.Text = 'Train All';
            app.MT_DNN_OptimizeSelectionButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_OptimizeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_OptimizeSelectionButtonPushed, true);
            app.MT_DNN_OptimizeSelectionButton.Enable = 'off';
            app.MT_DNN_OptimizeSelectionButton.Text = 'Optimize Selection';
            app.MT_DNN_AddAllModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_AddAllModelsButtonPushed, true);
            app.MT_DNN_AddAllModelsButton.Text = 'Add All';
            app.MT_DNN_ParallelCheckBox = uicheckbox(app.MT_DeepNeuralNetworksPanel);
            app.MT_DNN_ParallelCheckBox.Enable = 'off';
            app.MT_DNN_ParallelCheckBox.Text = 'Parallel';
            app.MT_DNN_TrainingPlotsCheckBox = uicheckbox(app.MT_DeepNeuralNetworksPanel);
            app.MT_DNN_TrainingPlotsCheckBox.Enable = 'off';
            app.MT_DNN_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.MT_DNN_TrainingPlotsCheckBox.Value = true;
            app.MT_DNN_OptimizeAllButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_OptimizeAllButtonPushed, true);
            app.MT_DNN_OptimizeAllButton.Enable = 'off';
            app.MT_DNN_OptimizeAllButton.Text = 'Optimize All';
            app.MT_ClassicMachineLearningPanel = uipanel(app.ModelTrainingPanel);
            app.MT_ClassicMachineLearningPanel.Title = 'Classic Machine Learning';
            app.MT_CML_ModelsListBox = uilistbox(app.MT_ClassicMachineLearningPanel);
            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.Multiselect = 'on';
            app.MT_CML_ModelsListBox.Value = {};
            app.MT_CML_AddModelButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_AddModelButtonPushed, true);
            app.MT_CML_AddModelButton.Text = 'Add';
            app.MT_CML_ClearModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_ClearModelsButtonPushed, true);
            app.MT_CML_ClearModelsButton.Text = 'Clear All';
            app.MT_CML_TrainSelectedModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_TrainSelectedModelsButtonPushed, true);
            app.MT_CML_TrainSelectedModelsButton.Enable = 'off';
            app.MT_CML_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_CML_TrainAllModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_TrainAllModelsButtonPushed, true);
            app.MT_CML_TrainAllModelsButton.Enable = 'off';
            app.MT_CML_TrainAllModelsButton.Text = 'Train All';
            app.MT_CML_OptimizeSelectionButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_OptimizeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_OptimizeSelectionButtonPushed, true);
            app.MT_CML_OptimizeSelectionButton.Enable = 'off';
            app.MT_CML_OptimizeSelectionButton.Text = 'Optimize Selection';
            app.MT_CML_AddAllModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_AddAllModelsButtonPushed, true);
            app.MT_CML_AddAllModelsButton.Text = 'Add All';
            app.MT_CML_OptimizeAllButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_OptimizeAllButtonPushed, true);
            app.MT_CML_OptimizeAllButton.Enable = 'off';
            app.MT_CML_OptimizeAllButton.Text = 'Optimize All';
            app.MT_StatisticalModelsPanel = uipanel(app.ModelTrainingPanel);
            app.MT_StatisticalModelsPanel.Title = 'Statistical Models';
            app.MT_S_ModelsListBox = uilistbox(app.MT_StatisticalModelsPanel);
            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.Multiselect = 'on';
            app.MT_S_ModelsListBox.Value = {};
            app.MT_S_AddModelButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_AddModelButtonPushed, true);
            app.MT_S_AddModelButton.Text = 'Add';
            app.MT_S_ClearModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_ClearModelsButtonPushed, true);
            app.MT_S_ClearModelsButton.Text = 'Clear All';
            app.MT_S_TrainSelectedModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_TrainSelectedModelsButtonPushed, true);
            app.MT_S_TrainSelectedModelsButton.Enable = 'off';
            app.MT_S_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_S_TrainAllModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_TrainAllModelsButtonPushed, true);
            app.MT_S_TrainAllModelsButton.Enable = 'off';
            app.MT_S_TrainAllModelsButton.Text = 'Train All';
            app.MT_S_OptimizeSelectionButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_OptimizeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_OptimizeSelectionButtonPushed, true);
            app.MT_S_OptimizeSelectionButton.Enable = 'off';
            app.MT_S_OptimizeSelectionButton.Text = 'Optimize Selection';
            app.MT_S_AddAllModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_AddAllModelsButtonPushed, true);
            app.MT_S_AddAllModelsButton.Text = 'Add All';
            app.MT_S_OptimizeAllButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_OptimizeAllButtonPushed, true);
            app.MT_S_OptimizeAllButton.Enable = 'off';
            app.MT_S_OptimizeAllButton.Text = 'Optimize All';
            app.MT_PreviewPanel = uipanel(app.ModelTrainingPanel);
            app.MT_PreviewUIAxes = uiaxes(app.MT_PreviewPanel);
            app.MT_PreviewUIAxes.XTick = [];
            app.MT_PreviewUIAxes.YTick = [];
            app.MT_PreviewUIAxes.Visible = 'off';
            app.NoModelLoadedforPreviewLabel = uilabel(app.MT_PreviewPanel);
            app.NoModelLoadedforPreviewLabel.Text = 'No Model Loaded for Preview';
            app.MT_PreviewUITable = uitable(app.MT_PreviewPanel);
            app.MT_PreviewUITable.ColumnName = {'Parameter'; 'Value'};
            app.MT_PreviewUITable.RowName = {};
            app.MT_PreviewUITable.Visible = 'off';
            app.MT_ModelPreviewLabel = uilabel(app.ModelTrainingPanel);
            app.MT_ModelPreviewLabel.Text = 'Model Preview';
            app.MT_TrainAllButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainAllButtonPushed, true);
            app.MT_TrainAllButton.Text = 'Train All';
            app.MT_OptimizeAllButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_OptimizeAllButtonPushed, true);
            app.MT_OptimizeAllButton.Text = 'Optimize All';
            app.MT_LoadConfigFromFileButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_LoadConfigFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @MT_LoadConfigFromFileButtonPushed, true);
            app.MT_LoadConfigFromFileButton.Text = 'Load Config from File';
            app.MT_ExportConfigForSelectionButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_ExportConfigForSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MT_ExportConfigForSelectionButtonPushed, true);
            app.MT_ExportConfigForSelectionButton.Text = 'Export Config for Selection';
            app.DetectionEvaluationTab = uitab(app.MainPanel);
            app.DetectionEvaluationTab.Title = '1.4 (a) Detection';
            app.DetectionEvaluationPanel = uipanel(app.DetectionEvaluationTab);
            app.DE_TrainedModelsPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_TrainedModelsPanel.Title = 'Models (ranked)';
            app.DE_TrainedModelsListBox = uilistbox(app.DE_TrainedModelsPanel);
            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.Multiselect = 'on';
            app.DE_TrainedModelsListBox.Value = {};
            app.DE_SaveSelectedModelsButton = uibutton(app.DE_TrainedModelsPanel, 'push');
            app.DE_SaveSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @DE_SaveSelectedModelsButtonPushed, true);
            app.DE_SaveSelectedModelsButton.Text = 'Save selected Models';
            app.DE_ImportTrainedModelsButton = uibutton(app.DE_TrainedModelsPanel, 'push');
            app.DE_ImportTrainedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @DE_ImportTrainedModelsButtonPushed, true);
            app.DE_ImportTrainedModelsButton.Text = 'Import trained Models';
            app.SortbyLabel = uilabel(app.DE_TrainedModelsPanel);
            app.SortbyLabel.Text = 'Rank by:';
            app.DE_SortByDropDown = uidropdown(app.DE_TrainedModelsPanel);
            app.DE_SortByDropDown.Items = {'F1 Score (point-wise)', 'F1 Score (event-wise)', 'F1 Score (point-adjusted)', 'F1 Score (composite)', 'F0.5 Score (point-wise)', 'F0.5 Score (event-wise)', 'F0.5 Score (point-adjusted)', 'F0.5 Score (composite)', 'Precision (point-wise)', 'Precision (event-wise)', 'Precision (point-adjusted)', 'Recall (point-wise)', 'Recall (event-wise)', 'Recall (point-adjusted)'};
            app.DE_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_SortByDropDownValueChanged, true);
            app.DE_SortByDropDown.Value = 'F1 Score (point-wise)';
            app.DE_DetectionPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_DetectionPanel.Title = 'Detection';
            app.SelectfaultyDataLabel = uilabel(app.DE_DetectionPanel);
            app.SelectfaultyDataLabel.Text = 'Select faulty Data:';
            app.DE_FaultyDataSelectionDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_FaultyDataSelectionDropDown.Items = {};
            app.DE_FaultyDataSelectionDropDown.Value = {};
            app.DE_RunDetectionForSelectedButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForSelectedButtonPushed, true);
            app.DE_RunDetectionForSelectedButton.Text = 'Selected Models';
            app.DE_RunDetectionForLabel = uilabel(app.DE_DetectionPanel);
            app.DE_RunDetectionForLabel.Text = 'Run Detection for:';
            app.DE_RunDetectionForAllButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForAllButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForAllButtonPushed, true);
            app.DE_RunDetectionForAllButton.Text = 'All Models';
            app.DE_DetectionTabGroup = uitabgroup(app.DE_DetectionPanel);
            app.DE_PlotsTab = uitab(app.DE_DetectionTabGroup);
            app.DE_PlotsTab.Title = 'Plots';
            app.DE_PlotsAnomalyScoresUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_PlotsAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.DE_PlotsAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.DE_PlotsAnomalyScoresUIAxes, 'Score')
            zlabel(app.DE_PlotsAnomalyScoresUIAxes, 'Z')
            app.DE_PlotsDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_PlotsDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_PlotsDataUIAxes, 'Timestamp')
            ylabel(app.DE_PlotsDataUIAxes, 'Value')
            zlabel(app.DE_PlotsDataUIAxes, 'Z')
            app.DE_PlotsScoresUITable = uitable(app.DE_PlotsTab);
            app.DE_PlotsScoresUITable.ColumnName = {'Type'; 'Precision'; 'Recall'; 'F1 Score'; 'F0.5 Score'};
            app.DE_PlotsScoresUITable.RowName = {};
            app.DE_DetectionShownForLabel = uilabel(app.DE_PlotsTab);
            app.DE_DetectionShownForLabel.Text = 'Detection Shown for: None';
            app.DE_DetectionShownForLabel_2 = uilabel(app.DE_PlotsTab);
            app.DE_DetectionShownForLabel_2.Text = 'Detection Performance:';
            app.DE_TableTab = uitab(app.DE_DetectionTabGroup);
            app.DE_TableTab.Title = 'Table';
            app.DE_FullDetectionUITable = uitable(app.DE_TableTab);
            app.DE_FullDetectionUITable.ColumnName = {'Metric'};
            app.DE_FullDetectionUITable.RowName = {};
            app.DE_ExportTableButton = uibutton(app.DE_TableTab, 'push');
            app.DE_ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @DE_ExportTableButtonPushed, true);
            app.DE_ExportTableButton.Text = 'Export Table';
            app.RowwisemaximaLabel = uilabel(app.DE_TableTab);
            app.RowwisemaximaLabel.Text = 'Rowwise maxima';
            app.ColumnwisemaximaLabel = uilabel(app.DE_TableTab);
            app.ColumnwisemaximaLabel.Text = 'Columnwise maxima';
            app.LegendLabel = uilabel(app.DE_TableTab);
            app.LegendLabel.Text = 'Legend:';
            app.DE_ComputationTimeTab = uitab(app.DE_DetectionTabGroup);
            app.DE_ComputationTimeTab.Title = 'Computation Time';
            app.DE_CompTimeUIAxes = uiaxes(app.DE_ComputationTimeTab);
            title(app.DE_CompTimeUIAxes, 'Computation Time')
            xlabel(app.DE_CompTimeUIAxes, 'Models sorted')
            ylabel(app.DE_CompTimeUIAxes, 'Metric value')
            zlabel(app.DE_CompTimeUIAxes, 'Z')
            app.DE_CompTimeMetricSelectionButton = uibutton(app.DE_ComputationTimeTab, 'push');
            app.DE_CompTimeMetricSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DE_CompTimeMetricSelectionButtonPushed, true);
            app.DE_CompTimeMetricSelectionButton.Text = 'Metric Selection';
            app.ThresholdDropDownLabel = uilabel(app.DE_DetectionPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.DE_ThresholdDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_ThresholdDropDown.Items = {};
            app.DE_ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_ThresholdDropDownValueChanged, true);
            app.DE_ThresholdDropDown.Value = {};
            app.DE_AnomalyPaddingSlider = uislider(app.DE_DetectionPanel);
            app.DE_AnomalyPaddingSlider.Limits = [0 1000];
            app.DE_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @DE_AnomalyPaddingSliderValueChanged, true);
            app.DE_AnomalyPaddingSlider.Enable = 'off';
            app.DE_WindowSizeSlider = uislider(app.DE_DetectionPanel);
            app.DE_WindowSizeSlider.Limits = [0 50];
            app.DE_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_WindowSizeSliderValueChanged, true);
            app.DE_WindowSizeSlider.Enable = 'off';
            app.DE_ZRangeSlider = uislider(app.DE_DetectionPanel);
            app.DE_ZRangeSlider.Limits = [1 10];
            app.DE_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_ZRangeSliderValueChanged, true);
            app.DE_ZRangeSlider.Enable = 'off';
            app.DE_ZRangeSlider.Value = 1;
            app.DE_AnomalyPaddingLabel = uilabel(app.DE_DetectionPanel);
            app.DE_AnomalyPaddingLabel.Enable = 'off';
            app.DE_AnomalyPaddingLabel.Text = 'Anomaly Padding:';
            app.DE_WindowSizeLabel = uilabel(app.DE_DetectionPanel);
            app.DE_WindowSizeLabel.Enable = 'off';
            app.DE_WindowSizeLabel.Text = 'Window Size (%):';
            app.DE_ZRangeLabel = uilabel(app.DE_DetectionPanel);
            app.DE_ZRangeLabel.Enable = 'off';
            app.DE_ZRangeLabel.Text = 'Z-Range:';
            app.SimulinkDetectionTab = uitab(app.MainPanel);
            app.SimulinkDetectionTab.Title = '1.4 (b) Simulink Detection';
            app.SimulinkDetectionPanel = uipanel(app.SimulinkDetectionTab);
            app.SD_TrainedModelsPanel = uipanel(app.SimulinkDetectionPanel);
            app.SD_TrainedModelsPanel.Title = 'DNN Models';
            app.SD_TrainedModelsListBox = uilistbox(app.SD_TrainedModelsPanel);
            app.SD_TrainedModelsListBox.Items = {};
            app.SD_TrainedModelsListBox.Value = {};
            app.SD_SaveSelectedModelsButton = uibutton(app.SD_TrainedModelsPanel, 'push');
            app.SD_SaveSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @SD_SaveSelectedModelsButtonPushed, true);
            app.SD_SaveSelectedModelsButton.Text = 'Save selected Models';
            app.SD_ImportTrainedModelsButton = uibutton(app.SD_TrainedModelsPanel, 'push');
            app.SD_ImportTrainedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @SD_ImportTrainedModelsButtonPushed, true);
            app.SD_ImportTrainedModelsButton.Text = 'Import trained Models';
            app.SD_DetectionPanel = uipanel(app.SimulinkDetectionPanel);
            app.SD_DetectionPanel.Title = 'Detection';
            app.SD_FoundAnomaliesUIAxes = uiaxes(app.SD_DetectionPanel);
            title(app.SD_FoundAnomaliesUIAxes, 'Data with true Anomalies')
            xlabel(app.SD_FoundAnomaliesUIAxes, 'Timestamp')
            ylabel(app.SD_FoundAnomaliesUIAxes, 'Value')
            zlabel(app.SD_FoundAnomaliesUIAxes, 'Z')
            app.SD_TrueAnomaliesUIAxes = uiaxes(app.SD_DetectionPanel);
            title(app.SD_TrueAnomaliesUIAxes, 'Data with found Anomalies')
            xlabel(app.SD_TrueAnomaliesUIAxes, 'Timestamp')
            ylabel(app.SD_TrueAnomaliesUIAxes, 'Value')
            zlabel(app.SD_TrueAnomaliesUIAxes, 'Z')
            app.SD_RunDetectionForSelectedButton = uibutton(app.SD_DetectionPanel, 'push');
            app.SD_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SD_RunDetectionForSelectedButtonPushed, true);
            app.SD_RunDetectionForSelectedButton.Text = 'Run Detection';
            app.SD_OpenmodelCheckBox = uicheckbox(app.SD_DetectionPanel);
            app.SD_OpenmodelCheckBox.Text = 'Open model';
            app.ThresholdDropDownLabel_4 = uilabel(app.SD_DetectionPanel);
            app.ThresholdDropDownLabel_4.Text = 'Threshold:';
            app.SD_ThresholdDropDown = uidropdown(app.SD_DetectionPanel);
            app.SD_ThresholdDropDown.Items = {};
            app.SD_ThresholdDropDown.Value = {};
            app.SD_DetectionShownForLabel = uilabel(app.SD_DetectionPanel);
            app.SD_DetectionShownForLabel.Text = 'Detection shown for: None';
            app.SelectfaultyDataLabel_2 = uilabel(app.SD_DetectionPanel);
            app.SelectfaultyDataLabel_2.Text = 'Select faulty Data:';
            app.SD_FaultyDataSelectionDropDown = uidropdown(app.SD_DetectionPanel);
            app.SD_FaultyDataSelectionDropDown.Items = {};
            app.SD_FaultyDataSelectionDropDown.Value = {};
            app.DynamicSwitchTab = uitab(app.MainPanel);
            app.DynamicSwitchTab.Title = '1.6. Dynamic Switch';
            app.DynamicSwitchPanel = uipanel(app.DynamicSwitchTab);
            app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes = uiaxes(app.DynamicSwitchPanel);
            title(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Preview: Test Switch Dataset (preprocessed)')
            xlabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Value')
            zlabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Z')
            app.DS_ResultsUITable = uitable(app.DynamicSwitchPanel);
            app.DS_ResultsUITable.ColumnName = '';
            app.DS_ResultsUITable.RowName = {};
            app.DS_ResultsLabel = uilabel(app.DynamicSwitchPanel);
            app.DS_ResultsLabel.Text = 'Results:';
            app.DS_TrainingAndEvaluationPanel = uipanel(app.DynamicSwitchPanel);
            app.DS_TrainingAndEvaluationPanel.Title = 'Training and Evaluation';
            app.DS_AutoLabelDatasetButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_AutoLabelDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DS_AutoLabelDatasetButtonPushed, true);
            app.DS_AutoLabelDatasetButton.Enable = 'off';
            app.DS_AutoLabelDatasetButton.Text = 'Auto-label Dataset';
            app.DS_RunEvaluationsButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_RunEvaluationsButton.ButtonPushedFcn = createCallbackFcn(app, @DS_RunEvaluationsButtonPushed, true);
            app.DS_RunEvaluationsButton.Enable = 'off';
            app.DS_RunEvaluationsButton.Text = 'Run Evaluations';
            app.DS_TrainClassifierButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @DS_TrainClassifierButtonPushed, true);
            app.DS_TrainClassifierButton.Enable = 'off';
            app.DS_TrainClassifierButton.Text = 'Train Classifier';
            app.DS_AutoLabelLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.DS_EvaluationLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.ScoreforAutolabelingLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.ScoreforAutolabelingLabel.Text = 'Score for Auto-labeling:';
            app.DS_ScoreDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_ScoreDropDown.Items = {'F1 Score (point-wise)', 'F1 Score (event-wise)', 'F1 Score (point-adjusted)', 'F1 Score (composite)', 'F0.5 Score (point-wise)', 'F0.5 Score (event-wise)', 'F0.5 Score (point-adjusted)', 'F0.5 Score (composite)', 'Precision (point-wise)', 'Precision (event-wise)', 'Precision (point-adjusted)', 'Recall (point-wise)', 'Recall (event-wise)', 'Recall (point-adjusted)'};
            app.DS_ScoreDropDown.Value = 'F1 Score (point-wise)';
            app.ThresholdDropDownLabel_2 = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.ThresholdDropDownLabel_2.Text = 'Threshold:';
            app.DS_ThresholdDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_ThresholdDropDown.Items = {};
            app.DS_ThresholdDropDown.Value = {};
            app.DS_ExportButton = uibutton(app.DynamicSwitchPanel, 'push');
            app.DS_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DS_ExportButtonPushed, true);
            app.DS_ExportButton.Text = 'Export Table';
            app.SelectedChannelLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedChannelLabel_6.Text = 'Selected Channel:';
            app.DS_SelectedChannelTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedChannelTestSwitchDropDown.Items = {};
            app.DS_SelectedChannelTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedChannelTestSwitchDropDownValueChanged, true);
            app.DS_SelectedChannelTestSwitchDropDown.Value = {};
            app.SelectedFileLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedFileLabel_6.Text = 'Selected File:';
            app.DS_SelectedFileTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedFileTestSwitchDropDown.Items = {};
            app.DS_SelectedFileTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedFileTestSwitchDropDownValueChanged, true);
            app.DS_SelectedFileTestSwitchDropDown.Value = {};
            app.AutoRunTab = uitab(app.MainPanel);
            app.AutoRunTab.Title = '2. Auto Run';
            app.AutoRunPanel = uipanel(app.AutoRunTab);
            app.AR_AutoTrainingAndEvaluationPanel = uipanel(app.AutoRunPanel);
            app.AR_AutoTrainingAndEvaluationPanel.Title = 'Auto Training and Evaluation';
            app.AR_RunEvaluationButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_RunEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @AR_RunEvaluationButtonPushed, true);
            app.AR_RunEvaluationButton.Text = 'Run Evaluation';
            app.FractionofDatasettoUseLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.FractionofDatasettoUseLabel.Text = 'Fraction of Dataset to Use:';
            app.AR_UseFractionEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'numeric');
            app.AR_UseFractionEditField.Limits = [0.0001 1];
            app.AR_UseFractionEditField.Value = 1;
            app.BestFscoreThresholdLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.BestFscoreThresholdLabel.Text = 'Preprocessing Method:';
            app.AR_PreprocessingMethodDropDown = uidropdown(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_PreprocessingMethodDropDown.Items = {'Rescale [0, 1]', 'Standardize', 'Raw Data'};
            app.AR_PreprocessingMethodDropDown.Value = 'Rescale [0, 1]';
            app.AR_BrowseButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @AR_BrowseButtonPushed, true);
            app.AR_BrowseButton.Text = 'Browse...';
            app.AR_DatasetLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_DatasetLabel.Text = 'Dataset Folder:';
            app.AR_DatasetEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'text');
            app.AR_RatioTestValEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'numeric');
            app.AR_RatioTestValEditField.Limits = [0.01 0.99];
            app.AR_RatioTestValEditField.Enable = 'off';
            app.AR_RatioTestValEditField.Value = 0.5;
            app.AR_UseAnomalousValidationSetCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AR_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.AR_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.AR_RatioTestValLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_RatioTestValLabel.Enable = 'off';
            app.AR_RatioTestValLabel.Text = 'Ratio:';
            app.AR_ParallelCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_ParallelCheckBox.Text = 'Parallel Training';
            app.AR_TrainingPlotsCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.AR_AugmentedTrainingCheckBock = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.AR_AugmentedDataCheckBock = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentedDataCheckBock.ValueChangedFcn = createCallbackFcn(app, @AR_AugmentedDataCheckBockValueChanged, true);
            app.AR_AugmentedDataCheckBock.Text = 'Augmentated data';
            app.AR_VisualizeDataButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AR_VisualizeDataButtonPushed, true);
            app.AR_VisualizeDataButton.Text = 'Visualize data';
            app.AR_Choice0fAugmentationListBox = uilistbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_Choice0fAugmentationListBox.Items = {'white noise', 'random walk', 'global shift', 'attenuate extremum'};
            app.AR_Choice0fAugmentationListBox.ValueChangedFcn = createCallbackFcn(app, @AR_Choice0fAugmentationListBoxValueChanged, true);
            app.AR_Choice0fAugmentationListBox.Visible = 'off';
            app.AR_Choice0fAugmentationListBox.Value = 'white noise';
            app.AR_intensitySlider = uislider(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_intensitySlider.Visible = 'off';
            app.AR_ChoiceofaugmentationListBoxLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_ChoiceofaugmentationListBoxLabel.Visible = 'off';
            app.AR_ChoiceofaugmentationListBoxLabel.Text = 'Choice of augmentation';
            app.AR_intensitySliderLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_intensitySliderLabel.Visible = 'off';
            app.AR_intensitySliderLabel.Text = 'intensity';
            app.AR_ResultsUITable = uitable(app.AutoRunPanel);
            app.AR_ResultsUITable.ColumnName = '';
            app.AR_ResultsUITable.RowName = {};
            app.AR_ResultsLabel = uilabel(app.AutoRunPanel);
            app.AR_ResultsLabel.Text = 'Results Preview (See Folder Auto_Run_Results for Rest):';
            app.MT_DNN_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_DNN_DeleteMenu = uimenu(app.MT_DNN_ContextMenu);
            app.MT_DNN_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_DNN_DeleteMenuSelected, true);
            app.MT_DNN_DeleteMenu.Text = 'Delete';
            app.MT_DNN_ShowModelParametersMenu = uimenu(app.MT_DNN_ContextMenu);
            app.MT_DNN_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_DNN_ShowModelParametersMenuSelected, true);
            app.MT_DNN_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_DNN_ContextMenu
            app.MT_DNN_ModelsListBox.ContextMenu = app.MT_DNN_ContextMenu;
            app.MT_CML_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_CML_DeleteMenu = uimenu(app.MT_CML_ContextMenu);
            app.MT_CML_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_CML_DeleteMenuSelected, true);
            app.MT_CML_DeleteMenu.Text = 'Delete';
            app.MT_CML_ShowModelParametersMenu = uimenu(app.MT_CML_ContextMenu);
            app.MT_CML_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_CML_ShowModelParametersMenuSelected, true);
            app.MT_CML_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_CML_ContextMenu
            app.MT_CML_ModelsListBox.ContextMenu = app.MT_CML_ContextMenu;
            app.MT_S_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_S_DeleteMenu = uimenu(app.MT_S_ContextMenu);
            app.MT_S_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_S_DeleteMenuSelected, true);
            app.MT_S_DeleteMenu.Text = 'Delete';
            app.MT_S_ShowModelParametersMenu = uimenu(app.MT_S_ContextMenu);
            app.MT_S_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_S_ShowModelParametersMenuSelected, true);
            app.MT_S_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_S_ContextMenu
            app.MT_S_ModelsListBox.ContextMenu = app.MT_S_ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end